classdef SLM_interfaceCol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        lLabel_31                      matlab.ui.control.Label
        lLabel_8                       matlab.ui.control.Label
        lLabel_7                       matlab.ui.control.Label
        lLabel_6                       matlab.ui.control.Label
        lLabel_5                       matlab.ui.control.Label
        lLabel_28                      matlab.ui.control.Label
        lLabel_27                      matlab.ui.control.Label
        lLabel_26                      matlab.ui.control.Label
        lLabel_25                      matlab.ui.control.Label
        lLabel_20                      matlab.ui.control.Label
        lLabel_19                      matlab.ui.control.Label
        lLabel_18                      matlab.ui.control.Label
        lLabel_17                      matlab.ui.control.Label
        lLabel_24                      matlab.ui.control.Label
        lLabel_23                      matlab.ui.control.Label
        lLabel_22                      matlab.ui.control.Label
        lLabel_21                      matlab.ui.control.Label
        lLabel_16                      matlab.ui.control.Label
        lLabel_15                      matlab.ui.control.Label
        lLabel_14                      matlab.ui.control.Label
        lLabel_13                      matlab.ui.control.Label
        lLabel_12                      matlab.ui.control.Label
        lLabel_11                      matlab.ui.control.Label
        lLabel_10                      matlab.ui.control.Label
        lLabel_9                       matlab.ui.control.Label
        lLabel_4                       matlab.ui.control.Label
        lLabel_3                       matlab.ui.control.Label
        lLabel_2                       matlab.ui.control.Label
        lLabel                         matlab.ui.control.Label
        SavephaseButton                matlab.ui.control.Button
        SendtoSLMButton                matlab.ui.control.Button
        SimulationparametersPanel      matlab.ui.container.Panel
        dzEditField                    matlab.ui.control.NumericEditField
        dzEditFieldLabel               matlab.ui.control.Label
        w0EditField                    matlab.ui.control.NumericEditField
        w0EditFieldLabel               matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        LEditFieldLabel                matlab.ui.control.Label
        wlEditField                    matlab.ui.control.NumericEditField
        lambdaLabel                    matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        RunsimuationButton             matlab.ui.control.Button
        SLM_Lamp                       matlab.ui.control.Lamp
        connectedLampLabel             matlab.ui.control.Label
        snapshotButton                 matlab.ui.control.Button
        IntensityCCDresultsLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AlignmentTab                   matlab.ui.container.Tab
        ParametersPanel                matlab.ui.container.Panel
        SizePanel                      matlab.ui.container.Panel
        align_y                        matlab.ui.control.NumericEditField
        YsizeEditFieldLabel            matlab.ui.control.Label
        align_x                        matlab.ui.control.NumericEditField
        XsizeEditFieldLabel            matlab.ui.control.Label
        OrientationPanel               matlab.ui.container.Panel
        VerticalButton                 matlab.ui.control.Button
        HorizontalButton               matlab.ui.control.Button
        SLM_size_y                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        SLM_size_x                     matlab.ui.control.NumericEditField
        SLMsizeEditFieldLabel          matlab.ui.control.Label
        cameraDropDown                 matlab.ui.control.DropDown
        cameraDropDownLabel            matlab.ui.control.Label
        PhaseLabel_align               matlab.ui.control.Label
        IntensitiytheoreticalresultsLabel_2  matlab.ui.control.Label
        intensity_align                matlab.ui.control.UIAxes
        phase_align                    matlab.ui.control.UIAxes
        LaguerreGaussTab               matlab.ui.container.Tab
        ParametersPanel_LG             matlab.ui.container.Panel
        LGButton                       matlab.ui.control.Button
        LG_forked                      matlab.ui.control.Button
        LG_grating                     matlab.ui.control.Button
        GratingForkedPanel_LG          matlab.ui.container.Panel
        XLabel_7                       matlab.ui.control.Label
        LG_grooves_x                   matlab.ui.control.NumericEditField
        NgroovesLabel_7                matlab.ui.control.Label
        yLabel_7                       matlab.ui.control.Label
        LG_grooves_y                   matlab.ui.control.NumericEditField
        LaguerreGaussordersPanel       matlab.ui.container.Panel
        PhaserangeLabel                matlab.ui.control.Label
        LG_angle                       matlab.ui.control.NumericEditField
        angleEditField_5Label          matlab.ui.control.Label
        LG_aspect                      matlab.ui.control.NumericEditField
        aspectEditField_5Label         matlab.ui.control.Label
        LG_y_offset                    matlab.ui.control.NumericEditField
        XEditField_3Label_5            matlab.ui.control.Label
        LG_y_center                    matlab.ui.control.NumericEditField
        XEditField_2Label_5            matlab.ui.control.Label
        LG_radius                      matlab.ui.control.NumericEditField
        RadiusLabel                    matlab.ui.control.Label
        offsetLabel_5                  matlab.ui.control.Label
        LG_x_offset                    matlab.ui.control.NumericEditField
        centerLabel_5                  matlab.ui.control.Label
        LG_x_center                    matlab.ui.control.NumericEditField
        LG_phase_range                 matlab.ui.control.RangeSlider
        rmodeSpinner                   matlab.ui.control.Spinner
        rmodeSpinner_2Label            matlab.ui.control.Label
        amodeSpinner                   matlab.ui.control.Spinner
        amodeSpinner_2Label            matlab.ui.control.Label
        masksizePanel_2                matlab.ui.container.Panel
        LG_y_size                      matlab.ui.control.NumericEditField
        YsizeEditField_3Label          matlab.ui.control.Label
        LG_x_size                      matlab.ui.control.NumericEditField
        XsizeEditField_3Label          matlab.ui.control.Label
        IntensitiytheoreticalresultsLabel  matlab.ui.control.Label
        PhaseLabel_LG                  matlab.ui.control.Label
        phase_LG                       matlab.ui.control.UIAxes
        intensity_LG                   matlab.ui.control.UIAxes
        HermiteGaussTab                matlab.ui.container.Tab
        IntensitiytheoreticalresultsLabel_3  matlab.ui.control.Label
        PhaseLabel_4                   matlab.ui.control.Label
        ParametersPanel_3              matlab.ui.container.Panel
        HGButton                       matlab.ui.control.Button
        HG_forked                      matlab.ui.control.Button
        HG_grating                     matlab.ui.control.Button
        GratingForkedPanel_HG          matlab.ui.container.Panel
        XLabel_5                       matlab.ui.control.Label
        HG_grooves_x                   matlab.ui.control.NumericEditField
        NgroovesLabel_5                matlab.ui.control.Label
        yLabel_5                       matlab.ui.control.Label
        HG_grooves_y                   matlab.ui.control.NumericEditField
        HermiteGaussorderPanel         matlab.ui.container.Panel
        PhaserangeLabel_3              matlab.ui.control.Label
        HG_angle                       matlab.ui.control.NumericEditField
        angleEditField_4Label          matlab.ui.control.Label
        HG_aspect                      matlab.ui.control.NumericEditField
        aspectEditField_4Label         matlab.ui.control.Label
        HG_y_offset                    matlab.ui.control.NumericEditField
        XEditField_3Label_4            matlab.ui.control.Label
        HG_y_center                    matlab.ui.control.NumericEditField
        XEditField_2Label_4            matlab.ui.control.Label
        HG_scale                       matlab.ui.control.NumericEditField
        ScaleEditField_4Label          matlab.ui.control.Label
        offsetLabel_4                  matlab.ui.control.Label
        HG_x_offset                    matlab.ui.control.NumericEditField
        centerLabel_4                  matlab.ui.control.Label
        HG_x_center                    matlab.ui.control.NumericEditField
        HG_phase_range                 matlab.ui.control.RangeSlider
        YmodeSpinner                   matlab.ui.control.Spinner
        YmodeSpinner_2Label            matlab.ui.control.Label
        XmodeSpinner                   matlab.ui.control.Spinner
        XmodeSpinner_2Label            matlab.ui.control.Label
        masksizePanel_3                matlab.ui.container.Panel
        HG_y_size                      matlab.ui.control.NumericEditField
        YsizeEditField_4Label          matlab.ui.control.Label
        HG_x_size                      matlab.ui.control.NumericEditField
        XsizeEditField_4Label          matlab.ui.control.Label
        phase_HG                       matlab.ui.control.UIAxes
        intensity_HG                   matlab.ui.control.UIAxes
        BesselTab                      matlab.ui.container.Tab
        IntensitiytheoreticalresultsLabel_4  matlab.ui.control.Label
        PhaseLabel_5                   matlab.ui.control.Label
        ParametersPanel_4              matlab.ui.container.Panel
        BesselButton                   matlab.ui.control.Button
        ForkedmaskButton               matlab.ui.control.Button
        GratingmaskButton              matlab.ui.control.Button
        GratingForkedPanel_Bessel      matlab.ui.container.Panel
        XLabel_6                       matlab.ui.control.Label
        bessel_grooves_x               matlab.ui.control.NumericEditField
        NgroovesLabel_6                matlab.ui.control.Label
        yLabel_6                       matlab.ui.control.Label
        bessel_grooves_y               matlab.ui.control.NumericEditField
        BesselparametersPanel          matlab.ui.container.Panel
        PhaserangeLabel_2              matlab.ui.control.Label
        bessel_angle                   matlab.ui.control.NumericEditField
        angleEditFieldLabel            matlab.ui.control.Label
        bessel_aspect                  matlab.ui.control.NumericEditField
        aspectEditFieldLabel           matlab.ui.control.Label
        bessel_y_offset                matlab.ui.control.NumericEditField
        XEditField_3Label              matlab.ui.control.Label
        offsetLabel                    matlab.ui.control.Label
        bessel_x_offset                matlab.ui.control.NumericEditField
        centerLabel                    matlab.ui.control.Label
        bessel_y_center                matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        bessel_x_center                matlab.ui.control.NumericEditField
        bessel_scale                   matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        bessel_phase_range             matlab.ui.control.RangeSlider
        OrderSpinner                   matlab.ui.control.Spinner
        OrderSpinnerLabel              matlab.ui.control.Label
        masksizePanel_4                matlab.ui.container.Panel
        bessel_y_size                  matlab.ui.control.NumericEditField
        YsizeEditField_5Label          matlab.ui.control.Label
        bessel_x_size                  matlab.ui.control.NumericEditField
        XsizeEditField_5Label          matlab.ui.control.Label
        phase_bessel                   matlab.ui.control.UIAxes
        intensity_bessel               matlab.ui.control.UIAxes
        ExternalmaskTab                matlab.ui.container.Tab
        IntensitiytheoreticalresultsLabel_5  matlab.ui.control.Label
        ExternalphaseLabel             matlab.ui.control.Label
        LoadexternalphasemasksPanel    matlab.ui.container.Panel
        ClearButton                    matlab.ui.control.Button
        ClearallButton                 matlab.ui.control.Button
        AddnewButton                   matlab.ui.control.Button
        masks_list                     matlab.ui.control.ListBox
        intensity_external             matlab.ui.control.UIAxes
        external_phase                 matlab.ui.control.UIAxes
        GSTab                          matlab.ui.container.Tab
        GerchbergSaxtonAlgorithmPanel  matlab.ui.container.Panel
        loadmaskButton                 matlab.ui.control.Button
        runButton                      matlab.ui.control.Button
        SelectCenterButton             matlab.ui.control.Button
        ShowCenterButton               matlab.ui.control.Button
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        ApertureCheckBox               matlab.ui.control.CheckBox
        UIAxes4                        matlab.ui.control.UIAxes
        Tab                            matlab.ui.container.Tab
        SLMCam                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X;
        Y;
        x;
        y;
        SLM;
        SLM_resolution;
        width;
        height;
        phase;
        Phase;
        phase_map;
        phase_grating;
        phase_forked;
        video;
        phase_type;
        E0;
        h;
        delta;
        intensity;
        intensity_loc;
        pathname;
        NH=0;
        i=1;

    end
    
    
    methods (Access = public)
         
        function Send_to_SLM(app)           
         if app.SLM_Lamp.Color == [0 1 0]
            imshow(app.phase,[0 2*pi],'Parent',app.SLM)
         end
        end
        %  spiral mask
        function [mask] = spiral_mask(app)
             
             phi = angle(app.X+1i*app.Y);               %azimuthal angle
             m = app.TopologicalChargeSpinner.Value;
             mask=mod(m*phi,2*pi);
            
             
        end
        % find center of the object
        function [center, radius] = findCenter(~, img)
            minSize = 40;
            img = bwareopen(img,minSize);
            rgs = regionprops(img);
            [~,idx] = max([rgs.Area]);
            rgs(idx) = [];
            center = vertcat(rgs.Centroid);
            [center, radius] = minboundcircle(center(:,1),center(:,2));
            
        end
        % find center of the object manually
        function [row, col] = FindCenterMan(~,vid)
           img = getsnapshot(vid);
           if length(size(img))~= 2
                img= im2double(rgb2gray(img));
           end
            
       
            figure()
            imshow(img)
            set(gcf, 'pointer', 'arrow');
            zoom on;
            pause() % you can zoom with your mouse and when your image is okay, you press any key
            zoom off; % to escape the zoom mode
            [col,row] = ginput(2);
            zoom out; % go to the original size of your image
            close()
                
         end


        % forked mask
        function [mask] = forked_mask(~,phase1,phase2)
            mask = angle(exp(1i.*phase1+1i.*phase2));
        
            mask = (mask - min(mask(:))) / (max(mask(:)) - min(mask(:))) *2*pi; % Normalize to [0, 2*pi]
           
            
        end


        function b = show_matrix(app, matrix, component_name)
            b = false;
            if isprop(app, component_name) && isa(app.(component_name), 'matlab.ui.control.UIAxes')
                ax = app.(component_name);
                if contains(component_name, "intensity")
                    imagesc(app.x, app.y, matrix, 'Parent', ax);
                    colormap(ax, "jet");
                    cb= colorbar(ax);
                    
                elseif contains(component_name, "phase")
                    disp('phase')
                    imagesc(app.x, app.y, matrix, 'Parent', ax);
                    colormap(ax, "hot");
                    cb = colorbar(ax);
                   
                else
                    disp(['O componente ', component_name, ' não é válido ou não é um UIAxes.']);
                end
                % Alterar cor dos eixos
                ax.XColor = 'w';
                ax.YColor = 'w';
                cb.Color = 'w';
                b = true;
            end
        end


        function b = send_to_SLM(app)
            
            heds_show_phasevalues(app.phase);
        
        end

        function a = SetSimParam(app)

            lambda = app.wlEditField.Value *10^-9; % Comprimento de onda do laser em metros
            w0 = app.w0EditField.Value; % Raio da cintura do feixe Gaussiano em metros
            z = 1; % Distância de propagação em metros
            % Criação da grade espacial
            N = app.NEditField.Value; % Número de pontos na grade
            L = app.LEditField.Value; % Tamanho da grade em metros
            app.x = linspace(-L/2, L/2, N);
            app.y = linspace(-L/2, L/2, N);
            [X, Y] = meshgrid(app.x, app.y);
            r = sqrt(X.^2 + Y.^2);
            % Campo elétrico do feixe Gaussiano
            app.E0 = exp(-(r.^2)/w0^2);
            k = 2 * pi / lambda; % Número de onda
            app.delta = L / N; % Resolução espacial
            % Ddoa convolução 
            dz = app.dzEditField.Value;
            fX = (-N/2 : N/2-1) / (N*app.delta);
            [x2, y2] = meshgrid(lambda * dz * fX);
            app.h = exp(1i*k/(2*dz)*(x2.^2+y2.^2)) / (1i*lambda*dz);
            clear('fX');

        end
        

        % Função para convolução 2D
        function G = ft2(~,g, delta)
            % function G = ft2(g, delta)
            G = fftshift(fft2(fftshift(g))) * delta^2;
        end
        
        function g = ift2(~,G, delta_f)
            % function g = ift2(G, delta_f)
            N = size(G, 1);
            g = ifftshift(ifft2(ifftshift(G))) * (N * delta_f)^2;

        end

        
        function C = conv2D(app,A, B, delta)
            % function C = myconv2(A, B, delta)
            N = size(A, 1);

            C = ift2(app,ft2(app,A, delta) .* ft2(app,B, delta), 1/(N*delta));
        end
        
        
        function [xx, yy] = grid2D(app,sz, varargin)
            % Generate a grid of points with some options:
        
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   (default [0,0])
            %   - 'angle'       angle  -- Rotation angle about axis (default: 0 radians)
            %   - 'centre'      [x, y] -- centre location for lens (default: min(sz)/2)
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
        
            % Set parameteres
            p = inputParser;
            setParameters(app,p, sz);
            parse(p, varargin{:});
            % Generate coordinates
            [xx, yy] = meshgrid(1:sz(2), 1:sz(1));
            % Centralize
            xx = xx - p.Results.centre(1);
            yy = yy - p.Results.centre(2);
        
            % Rotate
            ang = p.Results.angle;
            
            xx = cos(ang) .* xx - sin(ang) .* yy;
            yy = sin(ang) .* xx + cos(ang) .* yy;
            % Reshape yy
            yy = yy * p.Results.aspect;
            % Translate
            xx = xx - p.Results.offset(1);
            yy = yy - p.Results.offset(2);
        end
        
        
        function setParameters(~,p, sz, varargin)
            % Set default values for grid parameters 
        
            assert(numel(sz) == 2, 'sz must be a vector of 2 elements');
        
            parameters = {'offset', 'angle','centre',  'aspect'};
        
            ip = inputParser;
        
            ip.addParameter('offset', [0, 0]);
            ip.addParameter('angle', 0);
            ip.addParameter('centre', (sz)/2);
            ip.addParameter('aspect', 1.0);
            ip.addParameter('skip', {});
            ip.parse(varargin{:});
        
            for pp = 1:length(parameters)
                if ~any(strcmpi(parameters{pp}, ip.Results.skip))
                    p.addParameter(parameters{pp}, ip.Results.(parameters{pp}));
                end
            end
        end

        
        function L = fastLaguerre(~,n, alpha, x)
            % (just to do the work faster than built in laguerre matlab
            % Calculate the generalized Laguerre polynomial of degree n and parameter alpha
            L = zeros(size(x));
            for k = 0:n
                coeff = (-1)^k * factorial(n + alpha) / (factorial(k) * factorial(n - k) * factorial(alpha + k));
                L = L + coeff * x.^k;
            end
        end

        
        function E_propagated = propagation(app)
            E_masked= app.E0.*exp(1i*app.phase);
            E_propagated = app.h .*app.conv2D((E_masked), (E_masked), app.delta);
        end

        function E_propagated = propagationH(app,phase)
            E_masked= app.E0.*exp(1i*phase);
            E_propagated = app.h .*app.conv2D((E_masked), abs(E_masked), app.delta);
        end
        
        % grating mask
        function [mask] = grating_mask(app,x_grooves,y_grooves,size)
            gx = x_grooves./size(1);
            gy = y_grooves./size(2);
            mask = mod(2*pi*(app.Y.*gy+app.X.*gx),2*pi);
        end
        
        function phase_mask = laguerre_gauss_phase_mask(app,sz, amode, rmode, varargin)
            % Generates the phase patterns for Laguerre-Gaussian beams
            % Parameters
            %   - sz -- size of the pattern [rows, cols]
            %   - amode -- azimuthal mode order
            %   - rmode -- radial mode orders 
            % Usage
            %   pattern = laguerre_gauss_phase_mask(sz, amode, rmode,...);
            %
            % Optional named parameters:
            %   - 'scale'       scale  -- radial scaling factor for pattern
            %   - 'centre'      [x, y] -- centre location for lens (default: sz/2)
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
            %   - 'angle'       angle  -- Rotation angle about axis (radians)
            %   - 'range'       [min_val, max_val] -- Range of phase values
        
            % Set parameters
            p = inputParser;
            
            setParameters(app,p, sz);  
            p.addParameter('radius', min(sz)/10);
            p.addParameter('range', [0, 2*pi]); % Default range
            p.parse(varargin{:});
        
            c = p.Results.centre;
            ofs = p.Results.offset;
            asp = p.Results.aspect;
            ang = p.Results.angle;
            range = p.Results.range;
            
            % Generate coordinates
            [xx, yy] = grid2D(app,sz, 'centre', c, 'offset', ofs, ...
                'aspect', asp, 'angle', ang);
        
            % Calculate the azimuthal part of the pattern
            phase_azimuthal = amode .* atan2(yy, xx);
        
            % Calculate the Laguerre polynomials in the radial direction
            rho = sqrt(xx.^2 + yy.^2);
            Lpoly_rho = fastLaguerre(app,rmode, abs(amode), (rho./p.Results.radius).^2);
        
            % Calculate the radial part of the phase
            phase_radial = (sign(Lpoly_rho) > 0) * pi;
        
            % Sum azimuthal and radial phases
            phase = phase_azimuthal + phase_radial;
        
            % Calculate the phase range
            phase_range = range(2) - range(1);
        
            % Normalize phase to the specified range
            phase_diff = range(1) - min(phase(:));
            phase_mask = phase + phase_diff;
            
            % Ensure phase is within the specified range
            phase_mask = mod(phase_mask - range(1), phase_range) + range(1);
        end


        function pattern = hermite_gauss_phase_mask(app,sz, xmode, ymode, varargin)
            % Generate Hermite-Gaussian mode phase mask
            % Usage:
            %   mask= hermite_gauss_phase_mask([300,300], amode, rmode,...);
            %
            % Parameters:
            %   - sz -- size of the pattern
            %   - xmode -- x mode order
            %   - ymode -- y mode orders
            %
            % Options:
            %   - 'offset'      [x, y] -- offset after applying transformations (default [0,0])
            %   - 'angle'       angle  -- Rotation angle about axis (default: 0 radians)
            %   - 'centre'      [x, y] -- centre location for lens (default: min(sz)/2)
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
            %   - 'scale'       scale  -- set the scale (default:min(sz)/10)
            %   - 'range'       [min_val, max_val] -- Range of phase values
        
            % Set parameters
            p = inputParser;
            setParameters(app,p, sz);
            % lambda = 1555e-9; r = 1e-3;
            % s = sqrt(sz(1) * sz(2)) / (2 * max(xmode, ymode));
            % s = sqrt(sz(1)^2 + sz(2)^2) / (8 * max(xmode, ymode));
        
            p.addParameter('scale', min(sz)/10);
            p.addParameter('range', [0, 2*pi]); % Default range
            p.parse(varargin{:});
        
            % Obtain parameters
            c = p.Results.centre;
            ofs = p.Results.offset;
            asp = p.Results.aspect;
            ang = p.Results.angle;
            scale = p.Results.scale;
            range = p.Results.range;
            
            % Generate coordinates
            [xx, yy] = grid2D(app,sz, 'centre', c, 'offset', ofs, ...
                'angle', ang, 'aspect', asp);
        
            % Apply scale to coordinates
            xx = xx ./ scale;
            yy = yy ./ scale;
            
            % Calculate pattern
            xr = linspace(min(xx(:)), max(xx(:)), ceil(sqrt(sz(2)^2 + sz(1)^2)));
            yr = linspace(min(yy(:)), max(yy(:)), ceil(sqrt(sz(2)^2 + sz(1)^2)));
            hx = hermite(xmode, cast(xr, 'like', 1));
            hy = hermite(ymode, cast(yr, 'like', 1));
            % pattern =hx.*hy; gradative
            % Interpolate Hermite polynomials
            hx_interp = interp1(xr, hx, xx, 'nearest', 'extrap');
            hy_interp = interp1(yr, hy, yy, 'nearest', 'extrap');
        
            % Generate pattern using vectorized operations
            pattern = prod(cat(3, hx_interp, hy_interp), 3);
        
            % Generate phase pattern
            pattern = ~(pattern >= 0);
            
            pattern = (pattern - min(pattern(:))) / (max(pattern(:)) - min(pattern(:))); % Normalize to [0, 1]
            pattern = pattern * (range(2) - range(1)) + range(1); % Scale to specified range
        
            %pattern = normalize(pattern, 'range', range);
                    % Avan Suinesiaputra (2024). Hermite polynomials (https://www.mathworks.com/matlabcentral/fileexchange/27746-hermite-polynomials),
        % MATLAB Central File Exchange. Retrieved May 13, 2024.
        function h = hermite(n,x)
            % HERMITE: compute the Hermite polynomials.
            % 
            %   h = hermite(n)
            %   h = hermite(n,x)
            % 
            % Inputs:
            %   - n is the order of the Hermite polynomial (n>=0).
            %   - x is (optional) values to be evaluated on the resulting Hermite
            %     polynomial function.
            % 
            % There are two possible outputs:
            % 1. If x is omitted then h is an array with (n+1) elements that contains
            %    coefficients of each Hermite polynomial term.
            %    E.g. calling h = hermite(3)
            %    will result h = [8 0 -12 0], i.e. 8x^3 - 12x
            % 
            % 2. If x is given, then h = Hn(x) and h is the same size of x.
            %    E.g., H2(x) = 4x^2 - 2
            %    calling h = hermite(2,[0 1 2])
            %    will result h = [-2 2 14]
            % 
            % More information:
            % - about the Hermite polynomial: http://mathworld.wolfram.com/HermitePolynomial.html
            % - some examples of this function:
            % http://suinotes.wordpress.com/2010/05/26/hermite-polynomials-with-matlab/
            % 
            % Authors: Avan Suinesiaputra (avan.sp@gmail.com)
            %          Fadillah Z Tala    (fadil.tala@gmail.com)
            % rev.
            % 26/05/2010 - first creation.
            %            - bug fixed: error when hermite(0,x) is called (x isn't empty)
            % 24/09/2010 - bug fixed: the size of x does match with y in line 50.
            %              (thanks to Shiguo Peng)
            % check n
            if( n<0 ), error('The order of Hermite polynomial must be greater than or equal to 0.'); end
            % again check n is an integer
            if( 0~=n-fix(n) ), error('The order of Hermite polynomial must be an integer.'); end
            % call the hermite recursive function.
            h = hermite_rec(n);
            % evaluate the hermite polynomial function, given x
            if( nargin==2 )
                y = h(end) * ones(size(x));
                p = 1;
                for i=length(h)-1:-1:1
                    y = y + h(i) * x.^p;
                    p = p+1;
                end
                
                % restore the shape of y, the same as x
                h = reshape(y,size(x));
            end
            function h = hermite_rec(n)
                % This is the reccurence construction of a Hermite polynomial, i.e.:
                %   H0(x) = 1
                %   H1(x) = 2x
                %   H[n+1](x) = 2x Hn(x) - 2n H[n-1](x)
                if( 0==n ), h = 1;
                elseif( 1==n ), h = [2 0];
                else
                    
                    h1 = zeros(1,n+1);
                    h1(1:n) = 2*hermite_rec(n-1);
                    
                    h2 = zeros(1,n+1);
                    h2(3:end) = 2*(n-1)*hermite_rec(n-2);
                    
                    h = h1 - h2;
                    
                end
            end
        end
        end

        function pattern = bessel_gauss_phase_mask(app,sz, mode, varargin)
            % Generates the phase patterns for Bessel-Gaussian beams
            % Parameters
            %   - sz -- size of the pattern ``[rows, cols]``
            %   - mode (integer) -- bessel function mode
            % Usage
            %   pattern = bessel_gauss_phase_mask(sz, mode, ...);
            %
            % Optional named parameters:
            %   - 'scale'       scale  -- radial scaling factor for pattern
            %   - 'centre'      [x, y] -- centre location for lens (default: sz/2)
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
            %   - 'angle'       angle  -- Rotation angle about axis (radians)
            
            % Set parameters
            p = inputParser;
            setParameters(app,p, sz);
            p.addParameter('scale', sqrt(sz(1)^2 + sz(2)^2)/100);
            p.addParameter('range', [0 2*pi]);
            p.parse(varargin{:});
        
        
            c = p.Results.centre;
            ofs = p.Results.offset;
            asp = p.Results.aspect;
            ang = p.Results.angle;
            range = p.Results.range;
            % Generate coordinates
            [xx,yy] = grid2D(app,sz, 'centre', c, 'offset', ofs, ...
                    'angle', ang, 'aspect', asp );
            
            r = sqrt(xx.^2 + yy.^2);
            % Apply scaling to the coordinates
            r = r ./ p.Results.scale;
            phi = atan2(yy, xx);
            % Calculate the amplitude
            amplitude = besselj(mode, r);
            
            % Calculate the phase
            pattern = angle(amplitude .* exp(-1i*mode*phi+pi)) + pi;
            phase = mod(pattern+pi,2*pi);
            phase_range = range(2) - range(1);
        
            % Normalize phase to the specified range
            phase_diff = range(1) - min(phase(:));
            phase_mask = phase + phase_diff;
            
            % Ensure phase is within the specified range
            pattern = mod(phase_mask - range(1), phase_range) + range(1);
        end
        
        
    end
    
    methods (Access = public,Static)


        function mask = mask_gen_vh(N,M,V)
            
            if(V)
                vertical_mask=zeros(N,M);
                vertical_mask(:, 1:M/2) = 2*pi; 
                %mask = SLM_functions.centralized_mask(vertical_mask);
                mask = vertical_mask;
            else
                horizontal_mask = zeros(N,M);
                horizontal_mask(1:N/2,: ) = 2*pi; 
                %mask = SLM_functions.centralized_mask(horizontal_mask) ;
                mask = horizontal_mask;
            end
            
        
        end
        function [c_mask] = centralized_mask(mask)

            % Especificações do SLM : dimensões da matriz
            target_N = 1080;  
            target_M = 1920;
            [N , M] = size(mask);
            if N == target_N || M == target_M
                c_mask = mask;
            else
                % matriz para centralizar a mascara no tamanho do sensor
                center_y = floor(target_N / 2);
                center_x = floor(target_M / 2);
                start_y = center_y - floor(N / 2);
                end_y = start_y + N - 1;
                start_x = center_x - floor(M / 2);
                end_x = start_x + M - 1;
                centralized_phase = zeros(target_N,target_M);
                centralized_phase(start_y:end_y, start_x:end_x) = mask;
                c_mask = centralized_phase;
            end
            
         end
        function [center,radius] = minboundcircle(x,y,hullflag)
    % minboundcircle: Compute the minimum radius enclosing circle of a set of (x,y) pairs
    % usage: [center,radius] = minboundcircle(x,y,hullflag)
    %
    % arguments: (input)
    %  x,y - vectors of points, describing points in the plane as
    %        (x,y) pairs. x and y must be the same size. If x and y
    %        are arrays, they will be unrolled to vectors.
    %
    %  hullflag - boolean flag - allows the user to disable the
    %        call to convhulln. This will allow older releases of
    %        matlab to use this code, with a possible time penalty.
    %        It also allows minboundellipse to call this code
    %        efficiently.
    % 
    %        hullflag = false --> do not use the convex hull
    %        hullflag = true  --> use the convex hull for speed
    %
    %        default: true
    %
    %
    % arguments: (output)
    %  center - 1x2 vector, contains the (x,y) coordinates of the
    %        center of the minimum radius enclosing circle
    %
    %  radius - scalar - denotes the radius of the minimum
    %        enclosing circle
    %
    %
    % Example usage:
    %   x = randn(50000,1);
    %   y = randn(50000,1);
    %   tic,[c,r] = minboundcircle(x,y);toc
    %
    %   Elapsed time is 0.171178 seconds.
    %
    %   c: [-0.2223 0.070526]
    %   r: 4.6358
    %
    %
    % See also: minboundrect
    %
    %
    % Author: John D'Errico
    % E-mail: woodchips@rochester.rr.com
    % Release: 1.0
    % Release date: 1/10/07
    % default for hullflag
    if (nargin<3) || isempty(hullflag)
      hullflag = true;
    elseif ~islogical(hullflag) && ~ismember(hullflag,[0 1])
      error 'hullflag must be true or false if provided'
    end
    % preprocess data
    x=x(:);
    y=y(:);
    % not many error checks to worry about
    n = length(x);
    if n~=length(y)
      error 'x and y must be the same sizes'
    end
    % start out with the convex hull of the points to
    % reduce the problem dramatically. Note that any
    % points in the interior of the convex hull are
    % never needed.
    if hullflag && (n>3)
      edges = convhulln([x,y]);
      % list of the unique points on the convex hull itself
      % convhulln returns them as edges
      edges = unique(edges(:));
      % exclude those points inside the hull as not relevant
      x = x(edges);
      y = y(edges);
        
    end
    % now we must find the enclosing circle of those that
    % remain.
    n = length(x);
    % special case small numbers of points. If we trip any
    % of these cases, then we are done, so return.
    switch n
      case 0
        % empty begets empty
        center = [];
        radius = [];
        return
      case 1
        % with one point, the center has radius zero
        center = [x,y];
        radius = 0;
        return
      case 2
        % only two points. center is at the midpoint
        center = [mean(x),mean(y)];
        radius = norm([x(1),y(1)] - center);
        return
      case 3
        % exactly 3 points
        [center,radius] = enc3(x,y);
        return
    end
    % more than 3 points.
    % Use an active set strategy.
    aset = 1:3; % arbitrary, but quite adequate
    iset = 4:n;
    % pick a tolerance
    tol = 10*eps*(max(abs(mean(x) - x)) + max(abs(mean(y) - y)));
    % Keep a list of old sets as tried to trap any cycles. we don't need to
    % retain a huge list of sets, but only a few of the best ones. Any cycle
    % must hit one of these sets. Really, I could have used a smaller list,
    % but this is a small enough size that who cares? Almost always we will
    % never even fill up this list anyway.
    old.sets = NaN(10,3);
    old.rads = inf(10,1);
    old.centers = NaN(10,2);
    flag = true;
    while flag
      % have we seen this set before? If so, then we have entered a cycle
      aset = sort(aset);
      if ismember(aset,old.sets,'rows')
        % we have seen it before, so trap out
        center = old.centers(1,:);
        radius = old.radius(1);
        
        % just reset flag then continue, and the while loop will terminate
        flag = false;
        continue
      end
      
      % get the enclosing circle for the current set
      [center,radius] = enc3(x(aset),y(aset));
      
      % is this better than something from the retained sets?
      if radius < old.rads(end)
        old.sets(end,:) = sort(aset);
        old.rads(end) = radius;
        old.centers(end,:) = center;
            
        % sort them in increasing order of the circle radii
        [old.rads,tags] = sort(old.rads,'ascend');
        old.sets = old.sets(tags,:);
        old.centers = old.centers(tags,:);
      end
      
      % are all the inactive set points inside the circle?
      r = sqrt((x(iset) - center(1)).^2 + (y(iset) - center(2)).^2);
      [rmax,k] = max(r);
      if (rmax - radius) <= tol
        % the active set enclosing circle also enclosed
        % all of the inactive points.
        flag = false;
      else
        % it must be true that we can replace one member of aset
        % with iset(k). Which one?
        s1 = [aset([2 3]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(1)),y(aset(1))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(1);
          aset = [iset(k),aset([2 3])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        s1 = [aset([1 3]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(2)),y(aset(2))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(2);
          aset = [iset(k),aset([1 3])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        s1 = [aset([1 2]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(3)),y(aset(3))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(3);
          aset = [iset(k),aset([1 2])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        
        % if we get through to this point, then something went wrong.
        % Active set problem. Increase tol, then try again.
        tol = 2*tol;
        
      end
      
    end
    end
        function [center,radius] = enc3(X,Y)
    % minimum radius enclosing circle for exactly 3 points
    %
    % x, y are 3x1 vectors
    % convert to complex
    xy = X + sqrt(-1)*Y;
    % just in case the points are collinear or nearly so, get
    % the interpoint distances, and test the farthest pair
    % to see if they work.
    Dij = @(XY,i,j) abs(XY(i) - XY(j));
    D12 = Dij(xy,1,2);
    D13 = Dij(xy,1,3);
    D23 = Dij(xy,2,3);
    % Find the most distant pair. Test if their circumcircle
    % also encloses the third point.
    if (D12>=D13) && (D12>=D23)
      center = (xy(1) + xy(2))/2;
      radius = D12/2;
      if abs(center - xy(3)) <= radius
        center = [real(center),imag(center)];
        return
      end
    elseif (D13>=D12) && (D13>=D23)
      center = (xy(1) + xy(3))/2;
      radius = D13/2;
      if abs(center - xy(2)) <= radius
        center = [real(center),imag(center)];
        return
      end
    elseif (D23>=D12) && (D23>=D13)
      center = (xy(2) + xy(3))/2;
      radius = D23/2;
      if abs(center - xy(1)) <= radius
        center = [real(center),imag(center)];
        return
      end
    end
    % if we drop down to here, then the points cannot
    % be collinear, so the resulting 2x2 linear system
    % of equations will not be singular.
    A = 2*[X(2)-X(1), Y(2)-Y(1); X(3)-X(1), Y(3)-Y(1)];
    rhs = [X(2)^2 - X(1)^2 + Y(2)^2 - Y(1)^2; ...
           X(3)^2 - X(1)^2 + Y(3)^2 - Y(1)^2];
         
    center = (A\rhs)';
    radius = norm(center - [X(1),Y(1)]);
    
    end


    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % detectar cameras:
            d.Value = .5;
            d.Message = 'Determining connected cameras';
            
            adaptors=imaqhwinfo;
            installed_adaptors = adaptors.InstalledAdaptors;
            
            if length(installed_adaptors)~=0
                for i=1:1:length(installed_adaptors)
                    info = imaqhwinfo(installed_adaptors{1,i});
                    installed_adaptors_vector(i)=length(info.DeviceInfo);    
                end
                
                indx = find(installed_adaptors_vector==1);
                
                disp(['Detected cameras: '])
                
                for i=1:1:length(indx)
                    disp(installed_adaptors{1,indx(i)})
                end
                
                
            else
            
            disp(['Detected cameras: none']); 
            
            end
            % detectar SLMs
            d.Value = .75;
            d.Message = 'Determining SLM resolution';
            
            Screen_positions = get(0,'MonitorPositions');
            No_of_screens = size(Screen_positions,1);
            
            if No_of_screens==1
                disp(['Detected SLMs: 0'])    
            else
                No_of_screens=No_of_screens-1;    
                disp(['Detected SLMs: ' num2str(No_of_screens) ' Resolution: ' num2str(Screen_positions(2,3)) 'x' num2str(Screen_positions(2,4))])    
            end
            
            % Screen parameters 
            Screen_positions = get(0,'MonitorPositions');
            No_of_screens = size(Screen_positions,1);
            
            if No_of_screens == 1
                % SLM not detected
                app.SLM_Lamp.Color = [0 1 0];
                
                % Defines default coordination system vectors
                app.SLM_size_x.Value = 1920;       % Resolution x
                app.SLM_size_y.Value = 1080;      % Resolution y
            else
                
                % SLM detected
                app.SLM_Lamp.Color = [0 1 0];
                app.SLM_resolution = Screen_positions(2,:);
                app.SLM_size_x.Value = app.SLM_resolution(3);
                app.SLM_size_y.Value = app.SLM_resolution(4);
                %figure('Name','SLM','Position',app.SLM_resolution,'MenuBar','none', 'ToolBar', 'none', 'WindowState', 'fullscreen', 'WindowStyle',"modal");
                % Create figure window
                app.fig= app.SLM;
                app.SLM_axes = axes('Parent',app.fig,'Units','pixels','Position',[0 0 app.SLM_resolution(3) app.SLM_resolution(4)]);
                
            end
            
            % 
            % addpath('C:\Users\LabDark\Desktop\Afonso\codigos_SLM\SLM Display SDK (MATLAB) v1.1.1 Examples\');
            % instrreset,
            % %% SLM
            % % Import SDK:
            % add_heds_path;
            % 
            % % Detect SLMs and open a window on the selected SLM:
            % heds_init_slm;
    

            %% CCD
            % imaqhwinfo('winvideo')
            % %testar com instrhwinfo ou serialportlist
            % % select the device with the name: 'DO USB Video Capture';
            % % e.g., 
            % ndevice = 1;
            % a = imaqhwinfo('winvideo',ndevice);
            % % Select the format for the aquisition;
            % nformat = 8;
            % vid = videoinput('winvideo',ndevice,a.SupportedFormats{nformat});
            % app.video = vid;
            % vidRes = app.video.VideoResolution;
            % nBands = app.video.NumberOfBands;
            % app.video.FramesPerTrigger = 20;
            % hImage = image(ones(vidRes(1),vidRes(2),nBands));


            % %    info=imaqhwinfo('winvideo');
                   % % vid=videoinput('winvideo',1,'YUY2_800x600');
                   % % vidReturnedColorspace='rgb';% originally it was ycrcb
                   % % img=getsnapshot(vid);
            % 

            % https://www.mathworks.com/help/imaq/videoinput.html
            % https://www.mathworks.com/matlabcentral/answers/96242-how-can-i-insert-live-video-into-a-matlab-gui-using-image-acquisition-toolbox

            % video_preview = preview(app.video,hImage);
            % video_preview.Parent = app.SLMCam; 


            app.width = app.align_x.Value;
            app.height = app.align_y.Value;
            app.phase_map = zeros(app.height,app.width);
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);

            % Simulação
            % Parâmetros comuns
            lambda = app.wlEditField.Value *10^-9; % Comprimento de onda do laser em metros
            w0 = app.w0EditField.Value; % Raio da cintura do feixe Gaussiano em metros
            z = 1; % Distância de propagação em metros
            % Criação da grade espacial
            N = app.NEditField.Value; % Número de pontos na grade
            L = app.LEditField.Value; % Tamanho da grade em metros
            app.x = linspace(-L/2, L/2, N);
            app.y = linspace(-L/2, L/2, N);
            [X, Y] = meshgrid(app.x, app.y);
            r = sqrt(X.^2 + Y.^2);
            % Campo elétrico do feixe Gaussiano
            app.E0 = exp(-(r.^2)/w0^2);
            k = 2 * pi / lambda; % Número de onda
            app.delta = L / N; % Resolução espacial
            % Ddoa convolução 
            dz = app.dzEditField.Value;
            fX = (-N/2 : N/2-1) / (N*app.delta);
            [x2, y2] = meshgrid(lambda * dz * fX);
            app.h = exp(1i*k/(2*dz)*(x2.^2+y2.^2)) / (1i*lambda*dz);
            clear('fX');


        end

        % Callback function
        function SpiralmaskButtonPushed(app, event)
            mask = app.spiral_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            app.show_matrix(app,mask,'phase_OAM')            
        end

        % Callback function
        function widthchanged(app, event)
            app.width = app.XsizeEditField_2.Value;
            app.height = app.YsizeEditField_2.Value;
            app.phase_map = zeros(app.height,app.width);
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
        end

        % Callback function
        function HeigthChanged(app, event)
            app.width = app.XsizeEditField_2.Value;
            app.height = app.YsizeEditField_2.Value;
            app.phase_map = zeros(app.height,app.width);
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
        end

        % Callback function
        function GratingmaskButtonPushed(app, event)
            mask = app.grating_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            imshow(mask,[0,2*pi],'Parent',app.UIAxes3);
            
        end

        % Callback function
        function ForkedmaskButtonPushed(app, event)
            mask = app.forked_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            heds_show_phasevalues(mask);
            imshow(mask,[0,2*pi],'Parent',app.UIAxes3);
        end

        % Button pushed function: VerticalButton
        function VerticalButtonPushed(app, event)
            mask = app.mask_gen_vh(app.align_y.Value,app.align_x.Value,true);
            app.phase = mask;
            app.intensity_loc = 'intensity_align';
            app.phase_type = "hermite";
            app.show_matrix(mask,'phase_align')
        end

        % Button pushed function: HorizontalButton
        function HorizontalButtonPushed(app, event)
            mask = app.mask_gen_vh(app.align_y.Value,app.align_x.Value,false);
            app.phase = mask;
            app.intensity_loc = 'intensity_align';
            app.phase_type = "hermite";
            app.show_matrix(mask,'phase_align')
        end

        % Callback function
        function snapshotButtonPushed(app, event)
             img = getsnapshot(app.video);
            [filename,path] = uiputfile('snapshot.png','Save file name');
            imwrite(mat2gray(img), [path filename]);
            image(img)
            figure(app.UIFigure);
        end

        % Button pushed function: ShowCenterButton
        function ShowCenterButtonPushed(app, event)
            img  = getsnapshot(app.video);
            [center, radius] = app.findCenter(app,img);
            xc = center(1) ; yc = center(2);
            imshow(img)
            hold on
            plot(xc,yc,'b+','LineWidth',2,app.UIAxes4);
            images.roi.Circle(gca,'Center',[xc yc], 'Radius',radius,'Color','g');
            

        end

        % Value changed function: ApertureCheckBox
        function ApertureCheckBoxValueChanged(app, event)
            %% getting center and radius for de aperture 
            [center, radius] =app.findCenter(app, getsnapshot(app.video));


        end

        % Button pushed function: SelectCenterButton
        function SelectCenterButtonPushed(app, event)
            
        end

        % Callback function
        function HGButtonPushed(app, event)
            %
            mask = app.hermite_gauss_phase_mask(app);
            
            imshow(mask,[],'Parent',app.UIAxes3);
        end

        % Callback function
        function LGButtonPushed(app, event)
            
            mask = app.laguerre_gauss_phase_mask(app);
            
            imshow(mask,[],'Parent',app.UIAxes3);
        end

        % Button pushed function: RunsimuationButton
        function RunsimuationButtonPushed(app, event)
            app.SetSimParam();
            if num2str(app.phase_type) =="hermite"
                E_pro = app.propagationH(app.phase/2);
                
             
            else 
                E_pro = app.propagation();
            end
            app.intensity = abs(E_pro).^2;
            app.show_matrix(app.intensity,app.intensity_loc);
        end

        % Button pushed function: LGButton
        function LGButtonPushed2(app, event)
            sz = [app.LG_x_size.Value , app.LG_y_size.Value];
            amode = app.amodeSpinner.Value;
            rmode = app.rmodeSpinner.Value;
            radius = app.LG_radius.Value;
            center = [app.LG_x_center.Value, app.LG_y_center.Value];
            offset = [app.LG_x_offset.Value, app.LG_y_offset.Value];
            angle = app.LG_angle.Value;
            range = app.LG_phase_range.Value*pi;
            aspect = app.LG_aspect.Value;
            mask = app.laguerre_gauss_phase_mask(sz,amode,rmode,...
            'radius',radius,'centre',center,'offset',offset,...
            'aspect',aspect,'angle' ,angle,'range',range);
            app.Phase = mask;
            app.phase = mask;
            app.phase_type = 'laguerre';
            app.intensity_loc = 'intensity_LG';
            app.show_matrix(mask,'phase_LG')
            app.width = sz(1);
            app.height = sz(2);


        end

        % Value changed function: LG_x_size
        function LG_x_sizeValueChanged(app, event)
            value = app.LG_x_size.Value;
            app.LG_x_center.Value = value/2;
        end

        % Value changed function: LG_y_size
        function LG_y_sizeValueChanged(app, event)
            value = app.LG_y_size.Value;
            app.LG_y_center.Value = value/2;
        end

        % Button pushed function: LG_grating
        function LG_gratingButtonPushed(app, event)
            
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
            xg = app.LG_grooves_x.Value;
            yg = app.LG_grooves_y.Value;
            size = [app.LG_x_size.Value , app.LG_y_size.Value];
            mask = app.grating_mask(xg,yg,size);
            app.phase_grating= mask;
            app.phase = mask;
            app.phase_type = 'hermite';
            app.intensity_loc = 'intensity_LG';
            app.show_matrix(app.phase,'phase_LG')

        end

        % Button pushed function: LG_forked
        function LG_forkedButtonPushed(app, event)
            app.LG_gratingButtonPushed(event);
            app.LGButtonPushed2(event)
            mask = app.forked_mask(app.Phase,app.phase_grating);
            app.phase = mask;
            app.intensity_loc = 'intensity_LG';
            app.show_matrix(mask,'phase_LG')
        end

        % Button pushed function: HGButton
        function HGButtonPushed2(app, event)
            sz = [app.HG_x_size.Value , app.HG_y_size.Value];
            xmode = app.XmodeSpinner.Value;
            ymode = app.YmodeSpinner.Value;
            scale = app.HG_scale.Value;
            center = [app.HG_x_center.Value, app.HG_y_center.Value];
            offset = [app.HG_x_offset.Value, app.HG_y_offset.Value];
            angle = app.HG_angle.Value;
            range = app.HG_phase_range.Value*pi;
            aspect = app.HG_aspect.Value;
            mask = app.hermite_gauss_phase_mask(sz,xmode,ymode,...
            'scale',scale,'centre',center,'offset',offset,...
            'aspect',aspect,'angle' ,angle,'range',range);
            app.Phase = mask*2;
            app.phase = app.Phase;
            app.phase_type = 'hermite';
            app.intensity_loc = 'intensity_HG';
            app.show_matrix(app.phase,'phase_HG')
            app.width = sz(1);
            app.height = sz(2);
           
        end

        % Value changed function: HG_x_size
        function HG_x_sizeValueChanged(app, event)
            value = app.HG_x_size.Value;
            app.HG_x_center.Value = value/2;
        end

        % Value changed function: HG_y_size
        function HG_y_sizeValueChanged(app, event)
            value = app.HG_y_size.Value;
            app.HG_y_center.Value = value/2;

        end

        % Button pushed function: HG_grating
        function HG_gratingButtonPushed(app, event)
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
            xg = app.HG_grooves_x.Value;
            yg = app.HG_grooves_y.Value;
            size = [app.HG_x_size.Value , app.HG_y_size.Value];
            mask = app.grating_mask(xg,yg,size);
            app.phase_grating= mask;
            app.phase = mask;
            app.phase_type = 'hermite';
            app.intensity_loc = 'intensity_HG';
            app.show_matrix(app.phase,'phase_HG')

        end

        % Button pushed function: HG_forked
        function HG_forkedButtonPushed(app, event)
            app.HG_gratingButtonPushed(event);
            app.HGButtonPushed2(event);
            mask = app.forked_mask(app.Phase,app.phase_grating);
            app.phase = mask;
            app.phase_type = 'hermite';

            app.intensity_loc = 'intensity_HG';
            app.show_matrix(mask,'phase_HG')
        end

        % Button pushed function: SendtoSLMButton
        function SendtoSLMButtonPushed(app, event)
            app.Send_to_SLM()

        end

        % Button pushed function: BesselButton
        function BesselButtonPushed(app, event)
            sz = [app.bessel_x_size.Value , app.bessel_y_size.Value];
            order = app.OrderSpinner.Value;
            scale = app.bessel_scale.Value;
            center = [app.bessel_x_center.Value, app.bessel_y_center.Value];
            offset = [app.bessel_x_offset.Value, app.bessel_y_offset.Value];
            angle = app.bessel_angle.Value;
            range = app.bessel_phase_range.Value*pi;
            aspect = app.bessel_aspect.Value;
            mask = bessel_gauss_phase_mask(app,sz,order,...
            'scale',scale,'centre',center,'offset',offset,...
            'aspect',aspect,'angle' ,angle,'range',range);
            app.Phase = mask;
            app.phase = mask;
            app.phase_type = 'bessel';
            app.intensity_loc = 'intensity_bessel';
            app.show_matrix(mask,'phase_bessel')
            app.width = sz(1);
            app.height = sz(2);
           
        end

        % Value changed function: bessel_x_size
        function bessel_x_sizeValueChanged(app, event)
            value = app.bessel_x_size.Value;
            app.bessel_x_center.Value = value/2;
            
        end

        % Value changed function: bessel_y_size
        function bessel_y_sizeValueChanged(app, event)
            value = app.bessel_y_size.Value;
            app.bessel_y_center.Value = value/2;
        end

        % Button pushed function: GratingmaskButton
        function GratingmaskButtonPushed2(app, event)
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
            xg = app.bessel_grooves_x.Value;
            yg = app.bessel_grooves_y.Value;
            size = [app.bessel_x_size.Value , app.bessel_y_size.Value];
            mask = app.grating_mask(xg,yg,size);
            app.phase_grating= mask;
            app.phase = mask;
            app.phase_type = 'hermite';
            app.intensity_loc = 'intensity_bessel';
            app.show_matrix(app.phase,'phase_bessel')

        end

        % Button pushed function: ForkedmaskButton
        function ForkedmaskButtonPushed2(app, event)
            app.GratingmaskButtonPushed2(event);
            app.BesselButtonPushed(event);
            mask = app.forked_mask(app.Phase,app.phase_grating);
            app.phase = mask;
            app.intensity_loc = 'intensity_bessel';
            app.show_matrix(mask,'phase_bessel')
        end

        % Button pushed function: AddnewButton
        function AddnewButtonPushed(app, event)
            [filename, app.pathname] = uigetfile({'*.jpg;*.bmp;*.png;', 'Graphics (*.jpg,*.bmp,*.png)'}, 'File Selector', 'MultiSelect', 'on');
            
            % Verifica a operação
            if isequal(filename, 0)
                disp('O user cancelou a operação de seleção de arquivo.');
            else
                % Verifica o tipo de filename 
                if ischar(filename)
                    N = 1; % Quando um único arquivo é selecionado, filename é uma string
                    file_list = {filename};
                else
                    N = numel(filename); % Quando múltiplos arquivos são selecionados, filename é um cell array
                    file_list = filename;
                end
            
                app.NH = app.NH + N;
                app.masks_list.Items(app.i:app.i + N - 1) = file_list;
                app.i = app.i + N;
            
                figure(app.UIFigure);
            end

            
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
              L = length(app.masks_list.Items);
              for L0 = 1:1:L
                  app.masks_list.Items(L0) = cellstr({''});
              end
              app.NH = 0;
              app.i = 1;
        end

        % Value changed function: masks_list
        function masks_listValueChanged(app, event)
            value = app.masks_list.Value;
            if isempty(value)
                disp("Não selecionou nenhuma mascara")
            else
                app.phase=im2double(imread([app.pathname value]))*2*pi;
                
                % Verify if its an RGB image
                if_rgb=ismember(3,size(app.phase));    
                
                % Load the image
                if if_rgb==1
                      app.phase=im2double(rgb2gray(imread([app.pathname app.masks_list.Value])))*2*pi;
                end
                app.show_matrix(app.phase,'external_phase')
                app.intensity_loc = 'intensity_external';
                if contains(value,"hermite") || contains(value,"HG")
                    app.phase_type = 'hermite';
    
                else
                    app.phase_type = 'other';
                end
            end
        end

        % Button pushed function: SavephaseButton
        function SavephaseButtonPushed(app, event)
        [filename, app.pathname] = uiputfile('phase.bmp', 'Save file name');
        
        % Verifica a operação
        if isequal(filename, 0) || isequal(app.pathname, 0)
            disp('O usuário cancelou a operação de salvamento.');
        else
            imwrite(mat2gray(app.phase, [0 2*pi]), fullfile(app.pathname, filename));
            figure(app.UIFigure);
        end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
        
        if isempty(app.masks_list.Value)
            disp('Nenhum item selecionado para remoção.');
            return;
        end
        
        % Encontrar o índice do item selecionado
        [isMember, idx] = ismember(app.masks_list.Value, app.masks_list.Items);
        
        if isMember
            % Remover o item da lista
            app.masks_list.Items(idx) = [];
        
            % Atualizar NH e o índice
            app.NH = app.NH - 1;
            
            % Atualizar o índice para o próximo item ou ajustar conforme necessário
            if app.NH == 0
                app.i = 1; % Reiniciar o índice se a lista estiver vazia
            else
                app.i = min(idx, app.NH); % Ajustar para o próximo item válido
            end
        
            % Atualizar a seleção (opcional)
            if ~isempty(app.masks_list.Items)
                app.masks_list.Value = app.masks_list.Items{1};
            else
                app.masks_list.Value = [];
            end
        else
            disp('Item selecionado não encontrado na lista.');
        end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.SLMCam = uiaxes(app.UIFigure);
            xlabel(app.SLMCam, 'X')
            ylabel(app.SLMCam, 'Y')
            zlabel(app.SLMCam, 'Z')
            colormap(app.SLMCam, 'jet')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.phase_align = uiaxes(app.AlignmentTab);
            xlabel(app.phase_align, 'X')
            ylabel(app.phase_align, 'Y')
            zlabel(app.phase_align, 'Z')
            app.intensity_align = uiaxes(app.AlignmentTab);
            xlabel(app.intensity_align, 'X')
            ylabel(app.intensity_align, 'Y')
            zlabel(app.intensity_align, 'Z')
            app.IntensitiytheoreticalresultsLabel_2 = uilabel(app.AlignmentTab);
            app.IntensitiytheoreticalresultsLabel_2.Text = 'Intensitiy - theoretical results';
            app.PhaseLabel_align = uilabel(app.AlignmentTab);
            app.PhaseLabel_align.Text = 'Phase';
            app.cameraDropDownLabel = uilabel(app.AlignmentTab);
            app.cameraDropDownLabel.Text = 'camera';
            app.cameraDropDown = uidropdown(app.AlignmentTab);
            app.cameraDropDown.Items = {''};
            app.cameraDropDown.Value = '';
            app.SLMsizeEditFieldLabel = uilabel(app.AlignmentTab);
            app.SLMsizeEditFieldLabel.Text = 'SLM size';
            app.SLM_size_x = uieditfield(app.AlignmentTab, 'numeric');
            app.SLM_size_x.Value = 1920;
            app.XEditFieldLabel = uilabel(app.AlignmentTab);
            app.XEditFieldLabel.Text = 'X';
            app.SLM_size_y = uieditfield(app.AlignmentTab, 'numeric');
            app.SLM_size_y.Value = 1080;
            app.ParametersPanel = uipanel(app.AlignmentTab);
            app.ParametersPanel.Title = 'Parameters';
            app.OrientationPanel = uipanel(app.ParametersPanel);
            app.OrientationPanel.Title = 'Orientation';
            app.HorizontalButton = uibutton(app.OrientationPanel, 'push');
            app.HorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalButtonPushed, true);
            app.HorizontalButton.Text = 'Horizontal';
            app.VerticalButton = uibutton(app.OrientationPanel, 'push');
            app.VerticalButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalButtonPushed, true);
            app.VerticalButton.Text = 'Vertical';
            app.SizePanel = uipanel(app.ParametersPanel);
            app.SizePanel.Title = 'Size';
            app.XsizeEditFieldLabel = uilabel(app.SizePanel);
            app.XsizeEditFieldLabel.Text = 'X size';
            app.align_x = uieditfield(app.SizePanel, 'numeric');
            app.align_x.Value = 1024;
            app.YsizeEditFieldLabel = uilabel(app.SizePanel);
            app.YsizeEditFieldLabel.Text = 'Y size';
            app.align_y = uieditfield(app.SizePanel, 'numeric');
            app.align_y.Value = 1024;
            app.LaguerreGaussTab = uitab(app.TabGroup);
            app.LaguerreGaussTab.Title = 'Laguerre-Gauss';
            app.intensity_LG = uiaxes(app.LaguerreGaussTab);
            xlabel(app.intensity_LG, 'X')
            ylabel(app.intensity_LG, 'Y')
            zlabel(app.intensity_LG, 'Z')
            app.phase_LG = uiaxes(app.LaguerreGaussTab);
            xlabel(app.phase_LG, 'X')
            ylabel(app.phase_LG, 'Y')
            zlabel(app.phase_LG, 'Z')
            app.PhaseLabel_LG = uilabel(app.LaguerreGaussTab);
            app.PhaseLabel_LG.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel = uilabel(app.LaguerreGaussTab);
            app.IntensitiytheoreticalresultsLabel.Text = 'Intensitiy - theoretical results';
            app.ParametersPanel_LG = uipanel(app.LaguerreGaussTab);
            app.ParametersPanel_LG.Title = 'Parameters';
            app.masksizePanel_2 = uipanel(app.ParametersPanel_LG);
            app.masksizePanel_2.Title = 'mask size';
            app.XsizeEditField_3Label = uilabel(app.masksizePanel_2);
            app.XsizeEditField_3Label.Text = 'X size';
            app.LG_x_size = uieditfield(app.masksizePanel_2, 'numeric');
            app.LG_x_size.ValueChangedFcn = createCallbackFcn(app, @LG_x_sizeValueChanged, true);
            app.LG_x_size.Value = 1024;
            app.YsizeEditField_3Label = uilabel(app.masksizePanel_2);
            app.YsizeEditField_3Label.Text = 'Y size';
            app.LG_y_size = uieditfield(app.masksizePanel_2, 'numeric');
            app.LG_y_size.Limits = [0 Inf];
            app.LG_y_size.ValueChangedFcn = createCallbackFcn(app, @LG_y_sizeValueChanged, true);
            app.LG_y_size.Value = 1024;
            app.LaguerreGaussordersPanel = uipanel(app.ParametersPanel_LG);
            app.LaguerreGaussordersPanel.Title = 'Laguerre-Gauss orders';
            app.amodeSpinner_2Label = uilabel(app.LaguerreGaussordersPanel);
            app.amodeSpinner_2Label.Text = 'a mode';
            app.amodeSpinner = uispinner(app.LaguerreGaussordersPanel);
            app.amodeSpinner.Value = 1;
            app.rmodeSpinner_2Label = uilabel(app.LaguerreGaussordersPanel);
            app.rmodeSpinner_2Label.Text = 'r mode';
            app.rmodeSpinner = uispinner(app.LaguerreGaussordersPanel);
            app.LG_phase_range = uislider(app.LaguerreGaussordersPanel, 'range');
            app.LG_phase_range.Limits = [-3 3];
            app.LG_phase_range.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.LG_phase_range.Value = [0 2];
            app.LG_x_center = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_x_center.Value = 512;
            app.centerLabel_5 = uilabel(app.LaguerreGaussordersPanel);
            app.centerLabel_5.Text = 'center';
            app.LG_x_offset = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.offsetLabel_5 = uilabel(app.LaguerreGaussordersPanel);
            app.offsetLabel_5.Text = 'offset';
            app.RadiusLabel = uilabel(app.LaguerreGaussordersPanel);
            app.RadiusLabel.Text = 'Radius';
            app.LG_radius = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_radius.Value = 25;
            app.XEditField_2Label_5 = uilabel(app.LaguerreGaussordersPanel);
            app.XEditField_2Label_5.Text = 'X';
            app.LG_y_center = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_y_center.Value = 512;
            app.XEditField_3Label_5 = uilabel(app.LaguerreGaussordersPanel);
            app.XEditField_3Label_5.Text = 'X';
            app.LG_y_offset = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.aspectEditField_5Label = uilabel(app.LaguerreGaussordersPanel);
            app.aspectEditField_5Label.Text = 'aspect';
            app.LG_aspect = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_aspect.Value = 1;
            app.angleEditField_5Label = uilabel(app.LaguerreGaussordersPanel);
            app.angleEditField_5Label.Text = 'angle';
            app.LG_angle = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.PhaserangeLabel = uilabel(app.LaguerreGaussordersPanel);
            app.PhaserangeLabel.Text = 'Phase range';
            app.GratingForkedPanel_LG = uipanel(app.ParametersPanel_LG);
            app.GratingForkedPanel_LG.Title = 'Grating/Forked';
            app.LG_grooves_y = uieditfield(app.GratingForkedPanel_LG, 'numeric');
            app.yLabel_7 = uilabel(app.GratingForkedPanel_LG);
            app.yLabel_7.Text = 'Y';
            app.NgroovesLabel_7 = uilabel(app.GratingForkedPanel_LG);
            app.NgroovesLabel_7.Text = 'Nº grooves ';
            app.LG_grooves_x = uieditfield(app.GratingForkedPanel_LG, 'numeric');
            app.LG_grooves_x.Value = 5;
            app.XLabel_7 = uilabel(app.GratingForkedPanel_LG);
            app.XLabel_7.Text = 'X';
            app.LG_grating = uibutton(app.ParametersPanel_LG, 'push');
            app.LG_grating.ButtonPushedFcn = createCallbackFcn(app, @LG_gratingButtonPushed, true);
            app.LG_grating.Text = 'Grating mask';
            app.LG_forked = uibutton(app.ParametersPanel_LG, 'push');
            app.LG_forked.ButtonPushedFcn = createCallbackFcn(app, @LG_forkedButtonPushed, true);
            app.LG_forked.Text = 'Forked mask';
            app.LGButton = uibutton(app.ParametersPanel_LG, 'push');
            app.LGButton.ButtonPushedFcn = createCallbackFcn(app, @LGButtonPushed2, true);
            app.LGButton.Text = 'L-G';
            app.HermiteGaussTab = uitab(app.TabGroup);
            app.HermiteGaussTab.Title = 'Hermite-Gauss';
            app.intensity_HG = uiaxes(app.HermiteGaussTab);
            xlabel(app.intensity_HG, 'X')
            ylabel(app.intensity_HG, 'Y')
            zlabel(app.intensity_HG, 'Z')
            app.phase_HG = uiaxes(app.HermiteGaussTab);
            xlabel(app.phase_HG, 'X')
            ylabel(app.phase_HG, 'Y')
            zlabel(app.phase_HG, 'Z')
            app.ParametersPanel_3 = uipanel(app.HermiteGaussTab);
            app.ParametersPanel_3.Title = 'Parameters';
            app.masksizePanel_3 = uipanel(app.ParametersPanel_3);
            app.masksizePanel_3.Title = 'mask size';
            app.XsizeEditField_4Label = uilabel(app.masksizePanel_3);
            app.XsizeEditField_4Label.Text = 'X size';
            app.HG_x_size = uieditfield(app.masksizePanel_3, 'numeric');
            app.HG_x_size.ValueChangedFcn = createCallbackFcn(app, @HG_x_sizeValueChanged, true);
            app.HG_x_size.Value = 1024;
            app.YsizeEditField_4Label = uilabel(app.masksizePanel_3);
            app.YsizeEditField_4Label.Text = 'Y size';
            app.HG_y_size = uieditfield(app.masksizePanel_3, 'numeric');
            app.HG_y_size.Limits = [0 Inf];
            app.HG_y_size.ValueChangedFcn = createCallbackFcn(app, @HG_y_sizeValueChanged, true);
            app.HG_y_size.Value = 1024;
            app.HermiteGaussorderPanel = uipanel(app.ParametersPanel_3);
            app.HermiteGaussorderPanel.Title = 'Hermite-Gauss order';
            app.XmodeSpinner_2Label = uilabel(app.HermiteGaussorderPanel);
            app.XmodeSpinner_2Label.Text = 'X mode';
            app.XmodeSpinner = uispinner(app.HermiteGaussorderPanel);
            app.XmodeSpinner.Value = 1;
            app.YmodeSpinner_2Label = uilabel(app.HermiteGaussorderPanel);
            app.YmodeSpinner_2Label.Text = 'Y mode';
            app.YmodeSpinner = uispinner(app.HermiteGaussorderPanel);
            app.HG_phase_range = uislider(app.HermiteGaussorderPanel, 'range');
            app.HG_phase_range.Limits = [-2 2];
            app.HG_phase_range.Value = [0 1];
            app.HG_x_center = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.HG_x_center.Value = 512;
            app.centerLabel_4 = uilabel(app.HermiteGaussorderPanel);
            app.centerLabel_4.Text = 'center';
            app.HG_x_offset = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.offsetLabel_4 = uilabel(app.HermiteGaussorderPanel);
            app.offsetLabel_4.Text = 'offset';
            app.ScaleEditField_4Label = uilabel(app.HermiteGaussorderPanel);
            app.ScaleEditField_4Label.Text = 'Scale';
            app.HG_scale = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.HG_scale.Value = 29.5;
            app.XEditField_2Label_4 = uilabel(app.HermiteGaussorderPanel);
            app.XEditField_2Label_4.Text = 'X';
            app.HG_y_center = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.HG_y_center.Value = 512;
            app.XEditField_3Label_4 = uilabel(app.HermiteGaussorderPanel);
            app.XEditField_3Label_4.Text = 'X';
            app.HG_y_offset = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.aspectEditField_4Label = uilabel(app.HermiteGaussorderPanel);
            app.aspectEditField_4Label.Text = 'aspect';
            app.HG_aspect = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.HG_aspect.Value = 1;
            app.angleEditField_4Label = uilabel(app.HermiteGaussorderPanel);
            app.angleEditField_4Label.Text = 'angle';
            app.HG_angle = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.PhaserangeLabel_3 = uilabel(app.HermiteGaussorderPanel);
            app.PhaserangeLabel_3.Text = 'Phase range';
            app.GratingForkedPanel_HG = uipanel(app.ParametersPanel_3);
            app.GratingForkedPanel_HG.Title = 'Grating/Forked';
            app.HG_grooves_y = uieditfield(app.GratingForkedPanel_HG, 'numeric');
            app.yLabel_5 = uilabel(app.GratingForkedPanel_HG);
            app.yLabel_5.Text = 'Y';
            app.NgroovesLabel_5 = uilabel(app.GratingForkedPanel_HG);
            app.NgroovesLabel_5.Text = 'Nº grooves ';
            app.HG_grooves_x = uieditfield(app.GratingForkedPanel_HG, 'numeric');
            app.HG_grooves_x.Value = 5;
            app.XLabel_5 = uilabel(app.GratingForkedPanel_HG);
            app.XLabel_5.Text = 'X';
            app.HG_grating = uibutton(app.ParametersPanel_3, 'push');
            app.HG_grating.ButtonPushedFcn = createCallbackFcn(app, @HG_gratingButtonPushed, true);
            app.HG_grating.Text = 'Grating mask';
            app.HG_forked = uibutton(app.ParametersPanel_3, 'push');
            app.HG_forked.ButtonPushedFcn = createCallbackFcn(app, @HG_forkedButtonPushed, true);
            app.HG_forked.Text = 'Forked mask';
            app.HGButton = uibutton(app.ParametersPanel_3, 'push');
            app.HGButton.ButtonPushedFcn = createCallbackFcn(app, @HGButtonPushed2, true);
            app.HGButton.Text = 'H-G';
            app.PhaseLabel_4 = uilabel(app.HermiteGaussTab);
            app.PhaseLabel_4.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel_3 = uilabel(app.HermiteGaussTab);
            app.IntensitiytheoreticalresultsLabel_3.Text = 'Intensitiy - theoretical results';
            app.BesselTab = uitab(app.TabGroup);
            app.BesselTab.Title = 'Bessel';
            app.intensity_bessel = uiaxes(app.BesselTab);
            xlabel(app.intensity_bessel, 'X')
            ylabel(app.intensity_bessel, 'Y')
            zlabel(app.intensity_bessel, 'Z')
            app.phase_bessel = uiaxes(app.BesselTab);
            xlabel(app.phase_bessel, 'X')
            ylabel(app.phase_bessel, 'Y')
            zlabel(app.phase_bessel, 'Z')
            app.ParametersPanel_4 = uipanel(app.BesselTab);
            app.ParametersPanel_4.Title = 'Parameters';
            app.masksizePanel_4 = uipanel(app.ParametersPanel_4);
            app.masksizePanel_4.Title = 'mask size';
            app.XsizeEditField_5Label = uilabel(app.masksizePanel_4);
            app.XsizeEditField_5Label.Text = 'X size';
            app.bessel_x_size = uieditfield(app.masksizePanel_4, 'numeric');
            app.bessel_x_size.ValueChangedFcn = createCallbackFcn(app, @bessel_x_sizeValueChanged, true);
            app.bessel_x_size.Value = 1024;
            app.YsizeEditField_5Label = uilabel(app.masksizePanel_4);
            app.YsizeEditField_5Label.Text = 'Y size';
            app.bessel_y_size = uieditfield(app.masksizePanel_4, 'numeric');
            app.bessel_y_size.Limits = [0 Inf];
            app.bessel_y_size.ValueChangedFcn = createCallbackFcn(app, @bessel_y_sizeValueChanged, true);
            app.bessel_y_size.Value = 1024;
            app.BesselparametersPanel = uipanel(app.ParametersPanel_4);
            app.BesselparametersPanel.Title = 'Bessel parameters';
            app.OrderSpinnerLabel = uilabel(app.BesselparametersPanel);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.BesselparametersPanel);
            app.bessel_phase_range = uislider(app.BesselparametersPanel, 'range');
            app.bessel_phase_range.Limits = [-2 2];
            app.bessel_phase_range.Value = [0 2];
            app.ScaleEditFieldLabel = uilabel(app.BesselparametersPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.bessel_scale = uieditfield(app.BesselparametersPanel, 'numeric');
            app.bessel_scale.Value = 7;
            app.bessel_x_center = uieditfield(app.BesselparametersPanel, 'numeric');
            app.bessel_x_center.Value = 512;
            app.XEditField_2Label = uilabel(app.BesselparametersPanel);
            app.XEditField_2Label.Text = 'X';
            app.bessel_y_center = uieditfield(app.BesselparametersPanel, 'numeric');
            app.bessel_y_center.Value = 512;
            app.centerLabel = uilabel(app.BesselparametersPanel);
            app.centerLabel.Text = 'center';
            app.bessel_x_offset = uieditfield(app.BesselparametersPanel, 'numeric');
            app.offsetLabel = uilabel(app.BesselparametersPanel);
            app.offsetLabel.Text = 'offset';
            app.XEditField_3Label = uilabel(app.BesselparametersPanel);
            app.XEditField_3Label.Text = 'X';
            app.bessel_y_offset = uieditfield(app.BesselparametersPanel, 'numeric');
            app.aspectEditFieldLabel = uilabel(app.BesselparametersPanel);
            app.aspectEditFieldLabel.Text = 'aspect';
            app.bessel_aspect = uieditfield(app.BesselparametersPanel, 'numeric');
            app.bessel_aspect.Value = 1;
            app.angleEditFieldLabel = uilabel(app.BesselparametersPanel);
            app.angleEditFieldLabel.Text = 'angle';
            app.bessel_angle = uieditfield(app.BesselparametersPanel, 'numeric');
            app.PhaserangeLabel_2 = uilabel(app.BesselparametersPanel);
            app.PhaserangeLabel_2.Text = 'Phase range';
            app.GratingForkedPanel_Bessel = uipanel(app.ParametersPanel_4);
            app.GratingForkedPanel_Bessel.Title = 'Grating/Forked';
            app.bessel_grooves_y = uieditfield(app.GratingForkedPanel_Bessel, 'numeric');
            app.yLabel_6 = uilabel(app.GratingForkedPanel_Bessel);
            app.yLabel_6.Text = 'Y';
            app.NgroovesLabel_6 = uilabel(app.GratingForkedPanel_Bessel);
            app.NgroovesLabel_6.Text = 'Nº grooves ';
            app.bessel_grooves_x = uieditfield(app.GratingForkedPanel_Bessel, 'numeric');
            app.bessel_grooves_x.Value = 5;
            app.XLabel_6 = uilabel(app.GratingForkedPanel_Bessel);
            app.XLabel_6.Text = 'X';
            app.GratingmaskButton = uibutton(app.ParametersPanel_4, 'push');
            app.GratingmaskButton.ButtonPushedFcn = createCallbackFcn(app, @GratingmaskButtonPushed2, true);
            app.GratingmaskButton.Text = 'Grating mask';
            app.ForkedmaskButton = uibutton(app.ParametersPanel_4, 'push');
            app.ForkedmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ForkedmaskButtonPushed2, true);
            app.ForkedmaskButton.Text = 'Forked mask';
            app.BesselButton = uibutton(app.ParametersPanel_4, 'push');
            app.BesselButton.ButtonPushedFcn = createCallbackFcn(app, @BesselButtonPushed, true);
            app.BesselButton.Text = 'Bessel';
            app.PhaseLabel_5 = uilabel(app.BesselTab);
            app.PhaseLabel_5.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel_4 = uilabel(app.BesselTab);
            app.IntensitiytheoreticalresultsLabel_4.Text = 'Intensitiy - theoretical results';
            app.ExternalmaskTab = uitab(app.TabGroup);
            app.ExternalmaskTab.Title = 'External mask';
            app.external_phase = uiaxes(app.ExternalmaskTab);
            xlabel(app.external_phase, 'X')
            ylabel(app.external_phase, 'Y')
            zlabel(app.external_phase, 'Z')
            app.intensity_external = uiaxes(app.ExternalmaskTab);
            xlabel(app.intensity_external, 'X')
            ylabel(app.intensity_external, 'Y')
            zlabel(app.intensity_external, 'Z')
            app.LoadexternalphasemasksPanel = uipanel(app.ExternalmaskTab);
            app.LoadexternalphasemasksPanel.Title = 'Load external phase masks';
            app.masks_list = uilistbox(app.LoadexternalphasemasksPanel);
            app.masks_list.Items = {''};
            app.masks_list.ValueChangedFcn = createCallbackFcn(app, @masks_listValueChanged, true);
            app.masks_list.Value = '';
            app.AddnewButton = uibutton(app.LoadexternalphasemasksPanel, 'push');
            app.AddnewButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewButtonPushed, true);
            app.AddnewButton.Text = 'Add new';
            app.ClearallButton = uibutton(app.LoadexternalphasemasksPanel, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.ClearButton = uibutton(app.LoadexternalphasemasksPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExternalphaseLabel = uilabel(app.ExternalmaskTab);
            app.ExternalphaseLabel.Text = 'External phase';
            app.IntensitiytheoreticalresultsLabel_5 = uilabel(app.ExternalmaskTab);
            app.IntensitiytheoreticalresultsLabel_5.Text = 'Intensitiy - theoretical results';
            app.GSTab = uitab(app.TabGroup);
            app.GSTab.Title = 'GS';
            app.UIAxes4 = uiaxes(app.GSTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            colormap(app.UIAxes4, 'jet')
            app.GerchbergSaxtonAlgorithmPanel = uipanel(app.GSTab);
            app.GerchbergSaxtonAlgorithmPanel.Title = 'Gerchberg  Saxton Algorithm';
            app.ApertureCheckBox = uicheckbox(app.GerchbergSaxtonAlgorithmPanel);
            app.ApertureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApertureCheckBoxValueChanged, true);
            app.ApertureCheckBox.Text = 'Aperture';
            app.IterationsEditFieldLabel = uilabel(app.GerchbergSaxtonAlgorithmPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.GerchbergSaxtonAlgorithmPanel, 'numeric');
            app.IterationsEditField.Value = 100;
            app.ShowCenterButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.ShowCenterButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCenterButtonPushed, true);
            app.ShowCenterButton.Text = 'Show Center';
            app.SelectCenterButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.SelectCenterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCenterButtonPushed, true);
            app.SelectCenterButton.Text = 'Select Center';
            app.runButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.runButton.Text = 'run';
            app.loadmaskButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.loadmaskButton.Text = 'load mask';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.IntensityCCDresultsLabel = uilabel(app.UIFigure);
            app.IntensityCCDresultsLabel.Text = 'Intensity - CCD results';
            app.snapshotButton = uibutton(app.UIFigure, 'push');
            app.snapshotButton.Text = 'snapshot';
            app.connectedLampLabel = uilabel(app.UIFigure);
            app.connectedLampLabel.Text = 'connected';
            app.SLM_Lamp = uilamp(app.UIFigure);
            app.RunsimuationButton = uibutton(app.UIFigure, 'push');
            app.RunsimuationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimuationButtonPushed, true);
            app.RunsimuationButton.Text = 'Run simuation';
            app.SimulationparametersPanel = uipanel(app.UIFigure);
            app.SimulationparametersPanel.Title = 'Simulation parameters';
            app.NEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.NEditField.Value = 1024;
            app.lambdaLabel = uilabel(app.SimulationparametersPanel);
            app.lambdaLabel.Text = 'wl';
            app.wlEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.wlEditField.Value = 1.55e-06;
            app.LEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.LEditField.Value = 0.2;
            app.w0EditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.w0EditFieldLabel.Text = 'w0';
            app.w0EditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.w0EditField.Value = 0.01;
            app.dzEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.dzEditFieldLabel.Text = 'dz';
            app.dzEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.dzEditField.Value = 0.1;
            app.SendtoSLMButton = uibutton(app.UIFigure, 'push');
            app.SendtoSLMButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoSLMButtonPushed, true);
            app.SendtoSLMButton.Text = 'Send to SLM';
            app.SavephaseButton = uibutton(app.UIFigure, 'push');
            app.SavephaseButton.ButtonPushedFcn = createCallbackFcn(app, @SavephaseButtonPushed, true);
            app.SavephaseButton.Text = 'Save phase';
            app.lLabel = uilabel(app.UIFigure);
            app.lLabel.Text = 'l';
            app.lLabel_2 = uilabel(app.UIFigure);
            app.lLabel_2.Text = 'l';
            app.lLabel_3 = uilabel(app.UIFigure);
            app.lLabel_3.Text = 'l';
            app.lLabel_4 = uilabel(app.UIFigure);
            app.lLabel_4.Text = 'l';
            app.lLabel_9 = uilabel(app.UIFigure);
            app.lLabel_9.Text = 'l';
            app.lLabel_10 = uilabel(app.UIFigure);
            app.lLabel_10.Text = 'l';
            app.lLabel_11 = uilabel(app.UIFigure);
            app.lLabel_11.Text = 'l';
            app.lLabel_12 = uilabel(app.UIFigure);
            app.lLabel_12.Text = 'l';
            app.lLabel_13 = uilabel(app.UIFigure);
            app.lLabel_13.Text = 'l';
            app.lLabel_14 = uilabel(app.UIFigure);
            app.lLabel_14.Text = 'l';
            app.lLabel_15 = uilabel(app.UIFigure);
            app.lLabel_15.Text = 'l';
            app.lLabel_16 = uilabel(app.UIFigure);
            app.lLabel_16.Text = 'l';
            app.lLabel_21 = uilabel(app.UIFigure);
            app.lLabel_21.Text = 'l';
            app.lLabel_22 = uilabel(app.UIFigure);
            app.lLabel_22.Text = 'l';
            app.lLabel_23 = uilabel(app.UIFigure);
            app.lLabel_23.Text = 'l';
            app.lLabel_24 = uilabel(app.UIFigure);
            app.lLabel_24.Text = 'l';
            app.lLabel_17 = uilabel(app.UIFigure);
            app.lLabel_17.Text = 'l';
            app.lLabel_18 = uilabel(app.UIFigure);
            app.lLabel_18.Text = 'l';
            app.lLabel_19 = uilabel(app.UIFigure);
            app.lLabel_19.Text = 'l';
            app.lLabel_20 = uilabel(app.UIFigure);
            app.lLabel_20.Text = 'l';
            app.lLabel_25 = uilabel(app.UIFigure);
            app.lLabel_25.Text = 'l';
            app.lLabel_26 = uilabel(app.UIFigure);
            app.lLabel_26.Text = 'l';
            app.lLabel_27 = uilabel(app.UIFigure);
            app.lLabel_27.Text = 'l';
            app.lLabel_28 = uilabel(app.UIFigure);
            app.lLabel_28.Text = 'l';
            app.lLabel_5 = uilabel(app.UIFigure);
            app.lLabel_5.Text = 'l';
            app.lLabel_6 = uilabel(app.UIFigure);
            app.lLabel_6.Text = 'l';
            app.lLabel_7 = uilabel(app.UIFigure);
            app.lLabel_7.Text = 'l';
            app.lLabel_8 = uilabel(app.UIFigure);
            app.lLabel_8.Text = 'l';
            app.lLabel_31 = uilabel(app.UIFigure);
            app.lLabel_31.Text = 'l';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_interfaceCol

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end