classdef SLM_interfacebackup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        connectedLamp                  matlab.ui.control.Lamp
        connectedLampLabel             matlab.ui.control.Label
        snapshotButton_2               matlab.ui.control.Button
        AutoUpdateSwitch_2             matlab.ui.control.Switch
        AutoUpdateSwitch_2Label        matlab.ui.control.Label
        IntensityCCDresultsLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AlignmentTab                   matlab.ui.container.Tab
        ParametersPanel                matlab.ui.container.Panel
        OrientationPanel               matlab.ui.container.Panel
        VerticalButton                 matlab.ui.control.Button
        HorizontalButton               matlab.ui.control.Button
        YsizeEditField                 matlab.ui.control.NumericEditField
        YsizeEditFieldLabel            matlab.ui.control.Label
        XsizeEditField                 matlab.ui.control.NumericEditField
        XsizeEditFieldLabel            matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        SLMsizeEditField               matlab.ui.control.NumericEditField
        SLMsizeEditFieldLabel          matlab.ui.control.Label
        cameraDropDown                 matlab.ui.control.DropDown
        cameraDropDownLabel            matlab.ui.control.Label
        UIAxes8_2                      matlab.ui.control.UIAxes
        UIAxes9_2                      matlab.ui.control.UIAxes
        PhaseLabel_3                   matlab.ui.control.Label
        IntensitiytheoreticalresultsLabel_2  matlab.ui.control.Label
        ModulationTab                  matlab.ui.container.Tab
        LaguerreGaussPanel             matlab.ui.container.Panel
        rmodeSpinner                   matlab.ui.control.Spinner
        rmodeSpinnerLabel              matlab.ui.control.Label
        amodeSpinner                   matlab.ui.control.Spinner
        amodeSpinnerLabel              matlab.ui.control.Label
        LGButton                       matlab.ui.control.Button
        masksizePanel                  matlab.ui.container.Panel
        YsizeEditField_2               matlab.ui.control.NumericEditField
        YsizeEditField_2Label          matlab.ui.control.Label
        XsizeEditField_2               matlab.ui.control.NumericEditField
        XsizeEditField_2Label          matlab.ui.control.Label
        GratingPanel                   matlab.ui.container.Panel
        ForkedmaskButton               matlab.ui.control.Button
        GratingmaskButton              matlab.ui.control.Button
        groovesX                       matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        NgroovesLabel                  matlab.ui.control.Label
        groovesY                       matlab.ui.control.NumericEditField
        yLabel                         matlab.ui.control.Label
        SpiralPanel                    matlab.ui.container.Panel
        TopologicalChargeSpinner       matlab.ui.control.Spinner
        TopologicalChargeSpinnerLabel  matlab.ui.control.Label
        SpiralmaskButton               matlab.ui.control.Button
        LaguerreGaussTab               matlab.ui.container.Tab
        SendtoSLMButton                matlab.ui.control.Button
        IntensitiytheoreticalresultsLabel  matlab.ui.control.Label
        PhaseLabel_2                   matlab.ui.control.Label
        ParametersPanel_2              matlab.ui.container.Panel
        LaguerreGaussordersPanel       matlab.ui.container.Panel
        rmodeSpinner_2                 matlab.ui.control.Spinner
        rmodeSpinner_2Label            matlab.ui.control.Label
        amodeSpinner_2                 matlab.ui.control.Spinner
        amodeSpinner_2Label            matlab.ui.control.Label
        LGButton_2                     matlab.ui.control.Button
        GratingForkedPanel             matlab.ui.container.Panel
        ForkedmaskButton_2             matlab.ui.control.Button
        GratingmaskButton_2            matlab.ui.control.Button
        groovesX_2                     matlab.ui.control.NumericEditField
        XLabel_2                       matlab.ui.control.Label
        NgroovesLabel_2                matlab.ui.control.Label
        groovesY_2                     matlab.ui.control.NumericEditField
        yLabel_2                       matlab.ui.control.Label
        masksizePanel_2                matlab.ui.container.Panel
        YsizeEditField_3               matlab.ui.control.NumericEditField
        YsizeEditField_3Label          matlab.ui.control.Label
        XsizeEditField_3               matlab.ui.control.NumericEditField
        XsizeEditField_3Label          matlab.ui.control.Label
        UIAxes9                        matlab.ui.control.UIAxes
        UIAxes8                        matlab.ui.control.UIAxes
        HermiteGaussTab                matlab.ui.container.Tab
        SendtoSLMButton_2              matlab.ui.control.Button
        IntensitiytheoreticalresultsLabel_3  matlab.ui.control.Label
        PhaseLabel_4                   matlab.ui.control.Label
        ParametersPanel_3              matlab.ui.container.Panel
        HermiteGaussorderPanel         matlab.ui.container.Panel
        HGButton_2                     matlab.ui.control.Button
        YmodeSpinner_2                 matlab.ui.control.Spinner
        YmodeSpinner_2Label            matlab.ui.control.Label
        XmodeSpinner_2                 matlab.ui.control.Spinner
        XmodeSpinner_2Label            matlab.ui.control.Label
        GratingForkedPanel_2           matlab.ui.container.Panel
        ForkedmaskButton_3             matlab.ui.control.Button
        GratingmaskButton_3            matlab.ui.control.Button
        groovesX_3                     matlab.ui.control.NumericEditField
        XLabel_3                       matlab.ui.control.Label
        NgroovesLabel_3                matlab.ui.control.Label
        groovesY_3                     matlab.ui.control.NumericEditField
        yLabel_3                       matlab.ui.control.Label
        masksizePanel_3                matlab.ui.container.Panel
        YsizeEditField_4               matlab.ui.control.NumericEditField
        YsizeEditField_4Label          matlab.ui.control.Label
        XsizeEditField_4               matlab.ui.control.NumericEditField
        XsizeEditField_4Label          matlab.ui.control.Label
        UIAxes9_3                      matlab.ui.control.UIAxes
        UIAxes8_3                      matlab.ui.control.UIAxes
        BesselTab                      matlab.ui.container.Tab
        SendtoSLMButton_3              matlab.ui.control.Button
        IntensitiytheoreticalresultsLabel_4  matlab.ui.control.Label
        PhaseLabel_5                   matlab.ui.control.Label
        ParametersPanel_4              matlab.ui.container.Panel
        BesselparametersPanel          matlab.ui.container.Panel
        PhaserangeSlider               matlab.ui.control.RangeSlider
        PhaserangeSliderLabel          matlab.ui.control.Label
        OrderSpinner                   matlab.ui.control.Spinner
        OrderSpinnerLabel              matlab.ui.control.Label
        GratingForkedPanel_3           matlab.ui.container.Panel
        ForkedmaskButton_4             matlab.ui.control.Button
        GratingmaskButton_4            matlab.ui.control.Button
        groovesX_4                     matlab.ui.control.NumericEditField
        XLabel_4                       matlab.ui.control.Label
        NgroovesLabel_4                matlab.ui.control.Label
        groovesY_4                     matlab.ui.control.NumericEditField
        yLabel_4                       matlab.ui.control.Label
        masksizePanel_4                matlab.ui.container.Panel
        YsizeEditField_5               matlab.ui.control.NumericEditField
        YsizeEditField_5Label          matlab.ui.control.Label
        XsizeEditField_5               matlab.ui.control.NumericEditField
        XsizeEditField_5Label          matlab.ui.control.Label
        UIAxes9_4                      matlab.ui.control.UIAxes
        UIAxes8_4                      matlab.ui.control.UIAxes
        ExternalmaskTab                matlab.ui.container.Tab
        loadtoslmButton                matlab.ui.control.Button
        loadnewmaskButton              matlab.ui.control.Button
        masksListBox                   matlab.ui.control.ListBox
        masksListBoxLabel              matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        GSTab                          matlab.ui.container.Tab
        GerchbergSaxtonAlgorithmPanel  matlab.ui.container.Panel
        loadmaskButton                 matlab.ui.control.Button
        runButton                      matlab.ui.control.Button
        SelectCenterButton             matlab.ui.control.Button
        ShowCenterButton               matlab.ui.control.Button
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        ApertureCheckBox               matlab.ui.control.CheckBox
        UIAxes4                        matlab.ui.control.UIAxes
        helpTab                        matlab.ui.container.Tab
        ExternalmaskTextArea           matlab.ui.control.TextArea
        ExternalmaskTextAreaLabel      matlab.ui.control.Label
        GSTextArea                     matlab.ui.control.TextArea
        GSTextAreaLabel                matlab.ui.control.Label
        modulationTextArea             matlab.ui.control.TextArea
        modulationTextAreaLabel        matlab.ui.control.Label
        alignmentTextArea              matlab.ui.control.TextArea
        alignmentTextAreaLabel         matlab.ui.control.Label
        SLMCam                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X;
        Y;
        Width;
        Height;
        phase;
        phase_map;
        video;

    end
    
    
    methods (Access = private)
        %% masks 

        %  spiral mask
        function [mask] = spiral_mask(app)
             
             phi = angle(app.X+1i*app.Y);               %azimuthal angle
             m = app.TopologicalChargeSpinner.Value;
             mask=mod(m*phi,2*pi);
            
             
        end


        function [center, radius] = findCenter(~, img)
            minSize = 40;
            img = bwareopen(img,minSize);
            rgs = regionprops(img);
            [~,idx] = max([rgs.Area]);
            rgs(idx) = [];
            center = vertcat(rgs.Centroid);
            [center, radius] = minboundcircle(center(:,1),center(:,2));
            
        end

        function [row, col] = FindCenterMan(~,vid)
           img = getsnapshot(vid);
           if length(size(img))~= 2
                img= im2double(rgb2gray(img));
           end
            
       
            figure()
            imshow(img)
            set(gcf, 'pointer', 'arrow');
            zoom on;
            pause() % you can zoom with your mouse and when your image is okay, you press any key
            zoom off; % to escape the zoom mode
            [col,row] = ginput(2);
            zoom out; % go to the original size of your image
            close()
                
         end

        % grating mask
        function [mask] = grating_mask(app)
           
            gx=app.groovesX.Value./app.Width; 
            gy=app.groovesY.Value./app.Height; 
            mask = mod(2*pi*(app.Y.*gy+app.X.*gx),2*pi);
           
        end

        % forked mask
        function [mask] = forked_mask(app)
            mask = mod(angle(exp(1i.*(app.spiral_mask+app.grating_mask))),2*pi);
            
        end

        function pattern = hermite_gauss_phase_mask(app, varargin)
            sz = [app.Width app.Height];
            xmode =app.XmodeSpinner.Value;
            ymode =app.YmodeSpinner.Value;
            % Generate Hermite-Gaussian mode phase mask
            % Usage:
            %   mask= hermite_gauss_phase_mask([300,300], amode, rmode,...);
            %
            % Parameters:
            %   - sz -- size of the pattern
            %   - xmode -- x mode order
            %   - ymode -- y mode orders
            %
            % Options:
            %   - 'offset'      [x, y] -- offset after applying transformations (default [0,0])
            %   - 'angle'       angle  -- Rotation angle about axis (default: 0 radians)
            %   - 'centre'      [x, y] -- centre location for lens (default: min(sz)/2)
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
            %   - 'scale'       scale  -- set the scale (default:min(sz)/10)
            %   - 'range'       [min_val, max_val] -- Range of phase values
        
            % Set parameters
            p = inputParser;
            setParameters(p, sz);
            lambda = 1555e-9; r = 1e-3;
            s = sqrt(sz(1) * sz(2)) / (2 * max(xmode, ymode));
            s = sqrt(sz(1) * sz(2)) / max(sz);
            s = (pi * r^2) / (lambda * (2 * max(xmode, ymode) + 1));
            s = sqrt(sz(1)^2 + sz(2)^2) / (2 * max(xmode, ymode) * r * lambda);
        
            p.addParameter('scale', min(sz)/10);
        
            p.addParameter('range', [0, 2*pi]); % Default range
            p.parse(varargin{:});
        
            % Obtain parameters
            c = p.Results.centre;
            ofs = p.Results.offset;
            asp = p.Results.aspect;
            ang = p.Results.angle;
            scale = p.Results.scale;
            range = p.Results.range;
            
            % Generate coordinates
            [xx, yy] = grid2D(sz, 'centre', c, 'offset', ofs, ...
                'angle', ang, 'aspect', asp);
        
            % Apply scale to coordinates
            xx = xx ./ scale;
            yy = yy ./ scale;
        
            % Calculate pattern
            xr = linspace(min(xx(:)), max(xx(:)), ceil(sqrt(sz(2)^2 + sz(1)^2)));
            yr = linspace(min(yy(:)), max(yy(:)), ceil(sqrt(sz(2)^2 + sz(1)^2)));
        
            hx = hermiteH(xmode, cast(xr, 'like', 1));
            hy = hermiteH(ymode, cast(yr, 'like', 1));
        
            pattern = -ones(sz, 'like', xx);
        
            for ii = 2:length(xr)
                idx = xx >= xr(ii-1) & xx < xr(ii);
                pattern(idx) = pattern(idx) .* hx(ii-1);
            end
            idx = xx >= xr(end);
            pattern(idx) = pattern(idx) .* hx(end);
        
            for ii = 2:length(yr)
                idx = yy >= yr(ii-1) & yy < yr(ii);
                pattern(idx) = pattern(idx) .* hy(ii-1);
            end
            idx = yy >= yr(end);
            pattern(idx) = pattern(idx) .* hy(end);
        
            % Generate phase pattern
            
            pattern = (pattern >= 0);
            pattern = normalize(pattern,'range',range);
        end

        function phase_mask = laguerre_gauss_phase_mask(app,varargin)
             sz = [app.Width app.Height];
            amode =app.amodeSpinner.Value;
            rmode =app.rmodeSpinner.Value;
            % Generates the phase patterns for Laguerre-Gaussian beams
            % Parameters
            %   - sz -- size of the pattern [rows, cols]
            %   - amode -- azimuthal mode order
            %   - rmode -- radial mode orders 
            % Usage
            %   pattern = laguerre_gauss_phase_mask(sz, amode, rmode,...);
            %
            % Optional named parameters:
            %   - 'scale'       scale  -- radial scaling factor for pattern
            %   - 'centre'      [x, y] -- centre location for lens (default: sz/2)
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
            %   - 'angle'       angle  -- Rotation angle about axis (radians)
            %   - 'range'       [min_val, max_val] -- Range of phase values
        
            % Set parameters
            p = inputParser;
            setParameters(p, sz);  
            p.addParameter('radius', min(sz)/10);
            p.addParameter('range', [0, 2*pi]); % Default range
            p.parse(varargin{:});
        
            c = p.Results.centre;
            ofs = p.Results.offset;
            asp = p.Results.aspect;
            ang = p.Results.angle;
            range = p.Results.range;
            
            % Generate coordinates
            [xx, yy] = grid2D(sz, 'centre', c, 'offset', ofs, ...
                'aspect', asp, 'angle', ang);
        
            % Calculate the azimuthal part of the pattern
            phase_azimuthal = amode .* atan2(yy, xx);
        
            % Calculate the Laguerre polynomials in the radial direction
            rho = sqrt(xx.^2 + yy.^2);
            Lpoly_rho = fastLaguerre(rmode, abs(amode), (rho./p.Results.radius).^2);
        
            % Calculate the radial part of the phase
            phase_radial = (sign(Lpoly_rho) > 0) * pi;
        
            % Sum azimuthal and radial phases
            phase = phase_azimuthal + phase_radial;
        
            % Calculate the phase range
            phase_range = range(2) - range(1);
        
            % Normalize phase to the specified range
            phase_diff = range(1) - min(phase(:));
            phase_mask = phase + phase_diff;
            
            % Ensure phase is within the specified range
            phase_mask = mod(phase_mask - range(1), phase_range) + range(1);
        end
        function pattern = bessel_gauss_phase_mask(app, varargin)
             sz = [app.Width app.Height];
            mode =1;
           
            % Generates the phase patterns for Bessel-Gaussian beams
            % Parameters
            %   - sz -- size of the pattern ``[rows, cols]``
            %   - mode (integer) -- bessel function mode
            % Usage
            %   pattern = bessel_gauss_phase_mask(sz, mode, ...);
            %
            % Optional named parameters:
            %   - 'scale'       scale  -- radial scaling factor for pattern
            %   - 'centre'      [x, y] -- centre location for lens (default: sz/2)
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
            %   - 'angle'       angle  -- Rotation angle about axis (radians)
            
            % Set parameters
            p = inputParser;
            setParameters(p, sz);
            p.addParameter('scale', sqrt(sz(1)^2 + sz(2)^2)/100);
            p.addParameter('range', [0 2*pi]);
            p.parse(varargin{:});
        
        
            c = p.Results.centre;
            ofs = p.Results.offset;
            asp = p.Results.aspect;
            ang = p.Results.angle;
            range = p.Results.range;
            % Generate coordinates
            [xx,yy] = grid2D(sz, 'centre', c, 'offset', ofs, ...
                    'angle', ang, 'aspect', asp );
            
            r = sqrt(xx.^2 + yy.^2);
            % Apply scaling to the coordinates
            r = r ./ p.Results.scale;
            phi = atan2(yy, xx);
            % Calculate the amplitude
            amplitude = besselj(mode, r);
            
            % Calculate the phase
            pattern = angle(amplitude .* exp(-1i*mode*phi+pi)) + pi;
            phase = mod(pattern+pi,2*pi);
            phase_range = range(2) - range(1);
        
            % Normalize phase to the specified range
            phase_diff = range(1) - min(phase(:));
            phase_mask = phase + phase_diff;
            
            % Ensure phase is within the specified range
            pattern = mod(phase_mask - range(1), phase_range) + range(1);
        end


    end
    
    methods (Access = public,Static)
        
        function mask = mask_gen_vh(N,M,V)
            
            if(V)
                vertical_mask=zeros(N,M);
                vertical_mask(:, 1:M/2) = 2*pi; 
                %mask = SLM_functions.centralized_mask(vertical_mask);
                mask = vertical_mask;
            else
                horizontal_mask = zeros(N,M);
                horizontal_mask(1:N/2,: ) = 2*pi; 
                %mask = SLM_functions.centralized_mask(horizontal_mask) ;
                mask = horizontal_mask;
            end
            
        
        end
        function [c_mask] = centralized_mask(mask)

            % Especificações do SLM : dimensões da matriz
            target_N = 1080;  
            target_M = 1920;
            [N , M] = size(mask);
            if N == target_N || M == target_M
                c_mask = mask;
            else
                % matriz para centralizar a mascara no tamanho do sensor
                center_y = floor(target_N / 2);
                center_x = floor(target_M / 2);
                start_y = center_y - floor(N / 2);
                end_y = start_y + N - 1;
                start_x = center_x - floor(M / 2);
                end_x = start_x + M - 1;
                centralized_phase = zeros(target_N,target_M);
                centralized_phase(start_y:end_y, start_x:end_x) = mask;
                c_mask = centralized_phase;
            end
            
         end
        function [center,radius] = minboundcircle(x,y,hullflag)
    % minboundcircle: Compute the minimum radius enclosing circle of a set of (x,y) pairs
    % usage: [center,radius] = minboundcircle(x,y,hullflag)
    %
    % arguments: (input)
    %  x,y - vectors of points, describing points in the plane as
    %        (x,y) pairs. x and y must be the same size. If x and y
    %        are arrays, they will be unrolled to vectors.
    %
    %  hullflag - boolean flag - allows the user to disable the
    %        call to convhulln. This will allow older releases of
    %        matlab to use this code, with a possible time penalty.
    %        It also allows minboundellipse to call this code
    %        efficiently.
    % 
    %        hullflag = false --> do not use the convex hull
    %        hullflag = true  --> use the convex hull for speed
    %
    %        default: true
    %
    %
    % arguments: (output)
    %  center - 1x2 vector, contains the (x,y) coordinates of the
    %        center of the minimum radius enclosing circle
    %
    %  radius - scalar - denotes the radius of the minimum
    %        enclosing circle
    %
    %
    % Example usage:
    %   x = randn(50000,1);
    %   y = randn(50000,1);
    %   tic,[c,r] = minboundcircle(x,y);toc
    %
    %   Elapsed time is 0.171178 seconds.
    %
    %   c: [-0.2223 0.070526]
    %   r: 4.6358
    %
    %
    % See also: minboundrect
    %
    %
    % Author: John D'Errico
    % E-mail: woodchips@rochester.rr.com
    % Release: 1.0
    % Release date: 1/10/07
    % default for hullflag
    if (nargin<3) || isempty(hullflag)
      hullflag = true;
    elseif ~islogical(hullflag) && ~ismember(hullflag,[0 1])
      error 'hullflag must be true or false if provided'
    end
    % preprocess data
    x=x(:);
    y=y(:);
    % not many error checks to worry about
    n = length(x);
    if n~=length(y)
      error 'x and y must be the same sizes'
    end
    % start out with the convex hull of the points to
    % reduce the problem dramatically. Note that any
    % points in the interior of the convex hull are
    % never needed.
    if hullflag && (n>3)
      edges = convhulln([x,y]);
      % list of the unique points on the convex hull itself
      % convhulln returns them as edges
      edges = unique(edges(:));
      % exclude those points inside the hull as not relevant
      x = x(edges);
      y = y(edges);
        
    end
    % now we must find the enclosing circle of those that
    % remain.
    n = length(x);
    % special case small numbers of points. If we trip any
    % of these cases, then we are done, so return.
    switch n
      case 0
        % empty begets empty
        center = [];
        radius = [];
        return
      case 1
        % with one point, the center has radius zero
        center = [x,y];
        radius = 0;
        return
      case 2
        % only two points. center is at the midpoint
        center = [mean(x),mean(y)];
        radius = norm([x(1),y(1)] - center);
        return
      case 3
        % exactly 3 points
        [center,radius] = enc3(x,y);
        return
    end
    % more than 3 points.
    % Use an active set strategy.
    aset = 1:3; % arbitrary, but quite adequate
    iset = 4:n;
    % pick a tolerance
    tol = 10*eps*(max(abs(mean(x) - x)) + max(abs(mean(y) - y)));
    % Keep a list of old sets as tried to trap any cycles. we don't need to
    % retain a huge list of sets, but only a few of the best ones. Any cycle
    % must hit one of these sets. Really, I could have used a smaller list,
    % but this is a small enough size that who cares? Almost always we will
    % never even fill up this list anyway.
    old.sets = NaN(10,3);
    old.rads = inf(10,1);
    old.centers = NaN(10,2);
    flag = true;
    while flag
      % have we seen this set before? If so, then we have entered a cycle
      aset = sort(aset);
      if ismember(aset,old.sets,'rows')
        % we have seen it before, so trap out
        center = old.centers(1,:);
        radius = old.radius(1);
        
        % just reset flag then continue, and the while loop will terminate
        flag = false;
        continue
      end
      
      % get the enclosing circle for the current set
      [center,radius] = enc3(x(aset),y(aset));
      
      % is this better than something from the retained sets?
      if radius < old.rads(end)
        old.sets(end,:) = sort(aset);
        old.rads(end) = radius;
        old.centers(end,:) = center;
            
        % sort them in increasing order of the circle radii
        [old.rads,tags] = sort(old.rads,'ascend');
        old.sets = old.sets(tags,:);
        old.centers = old.centers(tags,:);
      end
      
      % are all the inactive set points inside the circle?
      r = sqrt((x(iset) - center(1)).^2 + (y(iset) - center(2)).^2);
      [rmax,k] = max(r);
      if (rmax - radius) <= tol
        % the active set enclosing circle also enclosed
        % all of the inactive points.
        flag = false;
      else
        % it must be true that we can replace one member of aset
        % with iset(k). Which one?
        s1 = [aset([2 3]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(1)),y(aset(1))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(1);
          aset = [iset(k),aset([2 3])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        s1 = [aset([1 3]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(2)),y(aset(2))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(2);
          aset = [iset(k),aset([1 3])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        s1 = [aset([1 2]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(3)),y(aset(3))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(3);
          aset = [iset(k),aset([1 2])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        
        % if we get through to this point, then something went wrong.
        % Active set problem. Increase tol, then try again.
        tol = 2*tol;
        
      end
      
    end
    end
                 % =======================================
        %  begin subfunction
        % =======================================
        function [center,radius] = enc3(X,Y)
    % minimum radius enclosing circle for exactly 3 points
    %
    % x, y are 3x1 vectors
    % convert to complex
    xy = X + sqrt(-1)*Y;
    % just in case the points are collinear or nearly so, get
    % the interpoint distances, and test the farthest pair
    % to see if they work.
    Dij = @(XY,i,j) abs(XY(i) - XY(j));
    D12 = Dij(xy,1,2);
    D13 = Dij(xy,1,3);
    D23 = Dij(xy,2,3);
    % Find the most distant pair. Test if their circumcircle
    % also encloses the third point.
    if (D12>=D13) && (D12>=D23)
      center = (xy(1) + xy(2))/2;
      radius = D12/2;
      if abs(center - xy(3)) <= radius
        center = [real(center),imag(center)];
        return
      end
    elseif (D13>=D12) && (D13>=D23)
      center = (xy(1) + xy(3))/2;
      radius = D13/2;
      if abs(center - xy(2)) <= radius
        center = [real(center),imag(center)];
        return
      end
    elseif (D23>=D12) && (D23>=D13)
      center = (xy(2) + xy(3))/2;
      radius = D23/2;
      if abs(center - xy(1)) <= radius
        center = [real(center),imag(center)];
        return
      end
    end
    % if we drop down to here, then the points cannot
    % be collinear, so the resulting 2x2 linear system
    % of equations will not be singular.
    A = 2*[X(2)-X(1), Y(2)-Y(1); X(3)-X(1), Y(3)-Y(1)];
    rhs = [X(2)^2 - X(1)^2 + Y(2)^2 - Y(1)^2; ...
           X(3)^2 - X(1)^2 + Y(3)^2 - Y(1)^2];
         
    center = (A\rhs)';
    radius = norm(center - [X(1),Y(1)]);
    
    end
        function setParameters(p, sz, varargin)
                % Set default values for grid parameters 
            
                assert(numel(sz) == 2, 'sz must be a vector of 2 elements');
            
                parameters = {'offset', 'angle','centre',  'aspect'};
            
                ip = inputParser;
            
                ip.addParameter('offset', [0, 0]);
                ip.addParameter('angle', 0);
                ip.addParameter('centre', (sz)/2);
                ip.addParameter('aspect', 1.0);
                ip.addParameter('skip', {});
                ip.parse(varargin{:});
            
                for pp = 1:length(parameters)
                    if ~any(strcmpi(parameters{pp}, ip.Results.skip))
                        p.addParameter(parameters{pp}, ip.Results.(parameters{pp}));
                    end
                end
            end
        function [xx, yy] = grid2D(sz, varargin)

            % Generate a grid of points with some options:
        
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   (default [0,0])
            %   - 'angle'       angle  -- Rotation angle about axis (default: 0 radians)
            %   - 'centre'      [x, y] -- centre location for lens (default: min(sz)/2)
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
        
            % Set parameteres
            p = inputParser;
            setParameters(p, sz);
            parse(p, varargin{:});
            % Generate coordinates
            [xx, yy] = meshgrid(1:sz(2), 1:sz(1));
            % Centralize
            xx = xx - p.Results.centre(1);
            yy = yy - p.Results.centre(2);
        
            % Rotate
            ang = p.Results.angle;
            
            xx = cos(ang) .* xx - sin(ang) .* yy;
            yy = sin(ang) .* xx + cos(ang) .* yy;
            % Reshape yy
            yy = yy * p.Results.aspect;
            % Translate
            xx = xx - p.Results.offset(1);
            yy = yy - p.Results.offset(2);
        end
        function L = fastLaguerre(n, alpha, x)
            % (just to do the work faster than built in laguerre matlab
            % Calculate the generalized Laguerre polynomial of degree n and parameter alpha
            L = zeros(size(x));
            for k = 0:n
                coeff = (-1)^k * factorial(n + alpha) / (factorial(k) * factorial(n - k) * factorial(alpha + k));
                L = L + coeff * x.^k;
            end
        end


    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            addpath('C:\Users\LabDark\Desktop\Afonso\codigos_SLM\SLM Display SDK (MATLAB) v1.1.1 Examples\');
            
            instrreset,

            %% SLM
            % Import SDK:
            add_heds_path;
            
            % Detect SLMs and open a window on the selected SLM:
            heds_init_slm;
            %% CCD
            imaqhwinfo('winvideo')
            %testar com instrhwinfo ou serialportlist
            % select the device with the name: 'DO USB Video Capture';
            % e.g., 
            ndevice = 1;
            a = imaqhwinfo('winvideo',ndevice);
            % Select the format for the aquisition;
            nformat = 8;
            vid = videoinput('winvideo',ndevice,a.SupportedFormats{nformat});
            app.video = vid;
            vidRes = app.video.VideoResolution;
            nBands = app.video.NumberOfBands;
            app.video.FramesPerTrigger = 20;
            hImage = image(ones(vidRes(1),vidRes(2),nBands));
            
            
            video_preview = preview(app.video,hImage);
            video_preview.Parent = app.SLMCam; 


            %imshow(data,'Parent',app.SLMCam)
            %drawnpow
           %imagesc(data, 'Parent',app.SLMCam)


            width = app.XsizeEditField_2.Value;
            height = app.YsizeEditField_2.Value;
            app.Width = width;
            app.Height = height;
            app.phase_map = zeros(height,width);
            x = -width/2:1:(width/2-1); 
            y = -height/2:1:(height/2-1);
            
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
        end

        % Button pushed function: SpiralmaskButton
        function SpiralmaskButtonPushed(app, event)
            heds_close_slm
            mask = app.spiral_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            heds_show_phasevalues(mask);
            imshow(mask,[0,2*pi],'Parent',app.UIAxes3);
            
        end

        % Value changed function: XsizeEditField_2
        function widthchanged(app, event)
            width = app.XsizeEditField_2.Value;
            height = app.YsizeEditField_2.Value;
            app.phase_map = zeros(height,width);
            x = -width/2:1:(width/2-1); 
            y = -height/2:1:(height/2-1);
            
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
        end

        % Value changed function: YsizeEditField_2
        function HeigthChanged(app, event)
            width = app.XsizeEditField_2.Value;
            height = app.YsizeEditField_2.Value;
            app.phase_map = zeros(height,width);
            x = -width/2:1:(width/2-1); 
            y = -height/2:1:(height/2-1);
           
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
        end

        % Button pushed function: GratingmaskButton
        function GratingmaskButtonPushed(app, event)
            heds_close_slm
            mask = app.grating_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            heds_show_phasevalues(mask);
            imshow(mask,[0,2*pi],'Parent',app.UIAxes3);
            
        end

        % Button pushed function: ForkedmaskButton
        function ForkedmaskButtonPushed(app, event)
            mask = app.forked_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            heds_show_phasevalues(mask);
            imshow(mask,[0,2*pi],'Parent',app.UIAxes3);
        end

        % Button pushed function: VerticalButton
        function VerticalButtonPushed(app, event)
            heds_close_slm
            mask = app.mask_gen_vh(app.YsizeEditField.Value,app.XsizeEditField.Value,true);
            heds_show_phasevalues(mask);
            imshow(mask,[],'Parent',app.UIAxesAlign);
        end

        % Button pushed function: HorizontalButton
        function HorizontalButtonPushed(app, event)
            heds_close_slm
            mask = app.mask_gen_vh(app.YsizeEditField.Value,app.XsizeEditField.Value,false);
            heds_show_phasevalues(mask);
            imshow(mask,[],'Parent',app.UIAxesAlign);
        end

        % Callback function: not associated with a component
        function snapshotButtonPushed(app, event)
             img = getsnapshot(app.video);
            [filename,path] = uiputfile('snapshot.png','Save file name');
            imwrite(mat2gray(img), [path filename]);
            image(img)
            figure(app.UIFigure);
        end

        % Button pushed function: ShowCenterButton
        function ShowCenterButtonPushed(app, event)
            img  = getsnapshot(app.video);
            [center, radius] = app.findCenter(app,img);
            xc = center(1) ; yc = center(2);
            imshow(img)
            hold on
            plot(xc,yc,'b+','LineWidth',2,app.UIAxes4);
            images.roi.Circle(gca,'Center',[xc yc], 'Radius',radius,'Color','g');
            

        end

        % Value changed function: ApertureCheckBox
        function ApertureCheckBoxValueChanged(app, event)
            %% getting center and radius for de aperture 
            [center, radius] =app.findCenter(app, getsnapshot(app.video));


        end

        % Button pushed function: SelectCenterButton
        function SelectCenterButtonPushed(app, event)
            
        end

        % Callback function: not associated with a component
        function HGButtonPushed(app, event)
            %heds_close_slm
            mask = app.hermite_gauss_phase_mask(app);
            %heds_show_phasevalues(mask);
            imshow(mask,[],'Parent',app.UIAxes3);
        end

        % Button pushed function: LGButton
        function LGButtonPushed(app, event)
            %heds_close_slm
            mask = app.laguerre_gauss_phase_mask(app);
            %heds_show_phasevalues(mask);
            imshow(mask,[],'Parent',app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.SLMCam = uiaxes(app.UIFigure);
            xlabel(app.SLMCam, 'X')
            ylabel(app.SLMCam, 'Y')
            zlabel(app.SLMCam, 'Z')
            colormap(app.SLMCam, 'jet')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.IntensitiytheoreticalresultsLabel_2 = uilabel(app.AlignmentTab);
            app.IntensitiytheoreticalresultsLabel_2.Text = 'Intensitiy - theoretical results';
            app.PhaseLabel_3 = uilabel(app.AlignmentTab);
            app.PhaseLabel_3.Text = 'Phase';
            app.UIAxes9_2 = uiaxes(app.AlignmentTab);
            title(app.UIAxes9_2, 'Title')
            xlabel(app.UIAxes9_2, 'X')
            ylabel(app.UIAxes9_2, 'Y')
            zlabel(app.UIAxes9_2, 'Z')
            app.UIAxes8_2 = uiaxes(app.AlignmentTab);
            title(app.UIAxes8_2, 'Title')
            xlabel(app.UIAxes8_2, 'X')
            ylabel(app.UIAxes8_2, 'Y')
            zlabel(app.UIAxes8_2, 'Z')
            app.cameraDropDownLabel = uilabel(app.AlignmentTab);
            app.cameraDropDownLabel.Text = 'camera';
            app.cameraDropDown = uidropdown(app.AlignmentTab);
            app.SLMsizeEditFieldLabel = uilabel(app.AlignmentTab);
            app.SLMsizeEditFieldLabel.Text = 'SLM size';
            app.SLMsizeEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.SLMsizeEditField.Value = 1920;
            app.XEditFieldLabel = uilabel(app.AlignmentTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.XEditField.Value = 1080;
            app.ParametersPanel = uipanel(app.AlignmentTab);
            app.ParametersPanel.Title = 'Parameters';
            app.XsizeEditFieldLabel = uilabel(app.ParametersPanel);
            app.XsizeEditFieldLabel.Text = 'X size';
            app.XsizeEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.XsizeEditField.Value = 1920;
            app.YsizeEditFieldLabel = uilabel(app.ParametersPanel);
            app.YsizeEditFieldLabel.Text = 'Y size';
            app.YsizeEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.YsizeEditField.Value = 1080;
            app.OrientationPanel = uipanel(app.ParametersPanel);
            app.OrientationPanel.Title = 'Orientation';
            app.HorizontalButton = uibutton(app.OrientationPanel, 'push');
            app.HorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalButtonPushed, true);
            app.HorizontalButton.Text = 'Horizontal';
            app.VerticalButton = uibutton(app.OrientationPanel, 'push');
            app.VerticalButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalButtonPushed, true);
            app.VerticalButton.Text = 'Vertical';
            app.ModulationTab = uitab(app.TabGroup);
            app.ModulationTab.Title = 'Modulation';
            app.SpiralPanel = uipanel(app.ModulationTab);
            app.SpiralPanel.Title = 'Spiral';
            app.SpiralmaskButton = uibutton(app.SpiralPanel, 'push');
            app.SpiralmaskButton.ButtonPushedFcn = createCallbackFcn(app, @SpiralmaskButtonPushed, true);
            app.SpiralmaskButton.Text = 'Spiral mask';
            app.TopologicalChargeSpinnerLabel = uilabel(app.SpiralPanel);
            app.TopologicalChargeSpinnerLabel.Text = 'Topological Charge';
            app.TopologicalChargeSpinner = uispinner(app.SpiralPanel);
            app.TopologicalChargeSpinner.Tag = 'to';
            app.TopologicalChargeSpinner.Value = 1;
            app.GratingPanel = uipanel(app.ModulationTab);
            app.GratingPanel.Title = 'Grating';
            app.yLabel = uilabel(app.GratingPanel);
            app.yLabel.Text = 'Y';
            app.groovesY = uieditfield(app.GratingPanel, 'numeric');
            app.NgroovesLabel = uilabel(app.GratingPanel);
            app.NgroovesLabel.Text = 'Nº grooves ';
            app.XLabel = uilabel(app.GratingPanel);
            app.XLabel.Text = 'X';
            app.groovesX = uieditfield(app.GratingPanel, 'numeric');
            app.groovesX.Value = 10;
            app.GratingmaskButton = uibutton(app.GratingPanel, 'push');
            app.GratingmaskButton.ButtonPushedFcn = createCallbackFcn(app, @GratingmaskButtonPushed, true);
            app.GratingmaskButton.Text = 'Grating mask';
            app.ForkedmaskButton = uibutton(app.GratingPanel, 'push');
            app.ForkedmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ForkedmaskButtonPushed, true);
            app.ForkedmaskButton.Text = 'Forked mask';
            app.masksizePanel = uipanel(app.ModulationTab);
            app.masksizePanel.Title = 'mask size';
            app.XsizeEditField_2Label = uilabel(app.masksizePanel);
            app.XsizeEditField_2Label.Text = 'X size';
            app.XsizeEditField_2 = uieditfield(app.masksizePanel, 'numeric');
            app.XsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @widthchanged, true);
            app.XsizeEditField_2.Value = 1024;
            app.YsizeEditField_2Label = uilabel(app.masksizePanel);
            app.YsizeEditField_2Label.Text = 'Y size';
            app.YsizeEditField_2 = uieditfield(app.masksizePanel, 'numeric');
            app.YsizeEditField_2.Limits = [0 Inf];
            app.YsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @HeigthChanged, true);
            app.YsizeEditField_2.Value = 1024;
            app.LaguerreGaussPanel = uipanel(app.ModulationTab);
            app.LaguerreGaussPanel.Title = 'Laguerre-Gauss';
            app.LGButton = uibutton(app.LaguerreGaussPanel, 'push');
            app.LGButton.ButtonPushedFcn = createCallbackFcn(app, @LGButtonPushed, true);
            app.LGButton.Text = 'L-G';
            app.amodeSpinnerLabel = uilabel(app.LaguerreGaussPanel);
            app.amodeSpinnerLabel.Text = 'a mode';
            app.amodeSpinner = uispinner(app.LaguerreGaussPanel);
            app.rmodeSpinnerLabel = uilabel(app.LaguerreGaussPanel);
            app.rmodeSpinnerLabel.Text = 'r mode';
            app.rmodeSpinner = uispinner(app.LaguerreGaussPanel);
            app.LaguerreGaussTab = uitab(app.TabGroup);
            app.LaguerreGaussTab.Title = 'Laguerre-Gauss';
            app.UIAxes8 = uiaxes(app.LaguerreGaussTab);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.LaguerreGaussTab);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.ParametersPanel_2 = uipanel(app.LaguerreGaussTab);
            app.ParametersPanel_2.Title = 'Parameters';
            app.masksizePanel_2 = uipanel(app.ParametersPanel_2);
            app.masksizePanel_2.Title = 'mask size';
            app.XsizeEditField_3Label = uilabel(app.masksizePanel_2);
            app.XsizeEditField_3Label.Text = 'X size';
            app.XsizeEditField_3 = uieditfield(app.masksizePanel_2, 'numeric');
            app.XsizeEditField_3.Value = 1024;
            app.YsizeEditField_3Label = uilabel(app.masksizePanel_2);
            app.YsizeEditField_3Label.Text = 'Y size';
            app.YsizeEditField_3 = uieditfield(app.masksizePanel_2, 'numeric');
            app.YsizeEditField_3.Limits = [0 Inf];
            app.YsizeEditField_3.Value = 1024;
            app.GratingForkedPanel = uipanel(app.ParametersPanel_2);
            app.GratingForkedPanel.Title = 'Grating/Forked';
            app.yLabel_2 = uilabel(app.GratingForkedPanel);
            app.yLabel_2.Text = 'Y';
            app.groovesY_2 = uieditfield(app.GratingForkedPanel, 'numeric');
            app.NgroovesLabel_2 = uilabel(app.GratingForkedPanel);
            app.NgroovesLabel_2.Text = 'Nº grooves ';
            app.XLabel_2 = uilabel(app.GratingForkedPanel);
            app.XLabel_2.Text = 'X';
            app.groovesX_2 = uieditfield(app.GratingForkedPanel, 'numeric');
            app.groovesX_2.Value = 5;
            app.GratingmaskButton_2 = uibutton(app.GratingForkedPanel, 'push');
            app.GratingmaskButton_2.Text = 'Grating mask';
            app.ForkedmaskButton_2 = uibutton(app.GratingForkedPanel, 'push');
            app.ForkedmaskButton_2.Text = 'Forked mask';
            app.LaguerreGaussordersPanel = uipanel(app.ParametersPanel_2);
            app.LaguerreGaussordersPanel.Title = 'Laguerre-Gauss orders';
            app.LGButton_2 = uibutton(app.LaguerreGaussordersPanel, 'push');
            app.LGButton_2.Text = 'L-G';
            app.amodeSpinner_2Label = uilabel(app.LaguerreGaussordersPanel);
            app.amodeSpinner_2Label.Text = 'a mode';
            app.amodeSpinner_2 = uispinner(app.LaguerreGaussordersPanel);
            app.amodeSpinner_2.Value = 1;
            app.rmodeSpinner_2Label = uilabel(app.LaguerreGaussordersPanel);
            app.rmodeSpinner_2Label.Text = 'r mode';
            app.rmodeSpinner_2 = uispinner(app.LaguerreGaussordersPanel);
            app.PhaseLabel_2 = uilabel(app.LaguerreGaussTab);
            app.PhaseLabel_2.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel = uilabel(app.LaguerreGaussTab);
            app.IntensitiytheoreticalresultsLabel.Text = 'Intensitiy - theoretical results';
            app.SendtoSLMButton = uibutton(app.LaguerreGaussTab, 'push');
            app.SendtoSLMButton.Text = 'Send to SLM';
            app.HermiteGaussTab = uitab(app.TabGroup);
            app.HermiteGaussTab.Title = 'Hermite-Gauss';
            app.UIAxes8_3 = uiaxes(app.HermiteGaussTab);
            title(app.UIAxes8_3, 'Title')
            xlabel(app.UIAxes8_3, 'X')
            ylabel(app.UIAxes8_3, 'Y')
            zlabel(app.UIAxes8_3, 'Z')
            app.UIAxes9_3 = uiaxes(app.HermiteGaussTab);
            title(app.UIAxes9_3, 'Title')
            xlabel(app.UIAxes9_3, 'X')
            ylabel(app.UIAxes9_3, 'Y')
            zlabel(app.UIAxes9_3, 'Z')
            app.ParametersPanel_3 = uipanel(app.HermiteGaussTab);
            app.ParametersPanel_3.Title = 'Parameters';
            app.masksizePanel_3 = uipanel(app.ParametersPanel_3);
            app.masksizePanel_3.Title = 'mask size';
            app.XsizeEditField_4Label = uilabel(app.masksizePanel_3);
            app.XsizeEditField_4Label.Text = 'X size';
            app.XsizeEditField_4 = uieditfield(app.masksizePanel_3, 'numeric');
            app.XsizeEditField_4.Value = 1024;
            app.YsizeEditField_4Label = uilabel(app.masksizePanel_3);
            app.YsizeEditField_4Label.Text = 'Y size';
            app.YsizeEditField_4 = uieditfield(app.masksizePanel_3, 'numeric');
            app.YsizeEditField_4.Limits = [0 Inf];
            app.YsizeEditField_4.Value = 1024;
            app.GratingForkedPanel_2 = uipanel(app.ParametersPanel_3);
            app.GratingForkedPanel_2.Title = 'Grating/Forked';
            app.yLabel_3 = uilabel(app.GratingForkedPanel_2);
            app.yLabel_3.Text = 'Y';
            app.groovesY_3 = uieditfield(app.GratingForkedPanel_2, 'numeric');
            app.NgroovesLabel_3 = uilabel(app.GratingForkedPanel_2);
            app.NgroovesLabel_3.Text = 'Nº grooves ';
            app.XLabel_3 = uilabel(app.GratingForkedPanel_2);
            app.XLabel_3.Text = 'X';
            app.groovesX_3 = uieditfield(app.GratingForkedPanel_2, 'numeric');
            app.groovesX_3.Value = 5;
            app.GratingmaskButton_3 = uibutton(app.GratingForkedPanel_2, 'push');
            app.GratingmaskButton_3.Text = 'Grating mask';
            app.ForkedmaskButton_3 = uibutton(app.GratingForkedPanel_2, 'push');
            app.ForkedmaskButton_3.Text = 'Forked mask';
            app.HermiteGaussorderPanel = uipanel(app.ParametersPanel_3);
            app.HermiteGaussorderPanel.Title = 'Hermite-Gauss order';
            app.XmodeSpinner_2Label = uilabel(app.HermiteGaussorderPanel);
            app.XmodeSpinner_2Label.Text = 'X mode';
            app.XmodeSpinner_2 = uispinner(app.HermiteGaussorderPanel);
            app.YmodeSpinner_2Label = uilabel(app.HermiteGaussorderPanel);
            app.YmodeSpinner_2Label.Text = 'Y mode';
            app.YmodeSpinner_2 = uispinner(app.HermiteGaussorderPanel);
            app.HGButton_2 = uibutton(app.HermiteGaussorderPanel, 'push');
            app.HGButton_2.Text = 'H-G';
            app.PhaseLabel_4 = uilabel(app.HermiteGaussTab);
            app.PhaseLabel_4.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel_3 = uilabel(app.HermiteGaussTab);
            app.IntensitiytheoreticalresultsLabel_3.Text = 'Intensitiy - theoretical results';
            app.SendtoSLMButton_2 = uibutton(app.HermiteGaussTab, 'push');
            app.SendtoSLMButton_2.Text = 'Send to SLM';
            app.BesselTab = uitab(app.TabGroup);
            app.BesselTab.Title = 'Bessel';
            app.UIAxes8_4 = uiaxes(app.BesselTab);
            title(app.UIAxes8_4, 'Title')
            xlabel(app.UIAxes8_4, 'X')
            ylabel(app.UIAxes8_4, 'Y')
            zlabel(app.UIAxes8_4, 'Z')
            app.UIAxes9_4 = uiaxes(app.BesselTab);
            title(app.UIAxes9_4, 'Title')
            xlabel(app.UIAxes9_4, 'X')
            ylabel(app.UIAxes9_4, 'Y')
            zlabel(app.UIAxes9_4, 'Z')
            app.ParametersPanel_4 = uipanel(app.BesselTab);
            app.ParametersPanel_4.Title = 'Parameters';
            app.masksizePanel_4 = uipanel(app.ParametersPanel_4);
            app.masksizePanel_4.Title = 'mask size';
            app.XsizeEditField_5Label = uilabel(app.masksizePanel_4);
            app.XsizeEditField_5Label.Text = 'X size';
            app.XsizeEditField_5 = uieditfield(app.masksizePanel_4, 'numeric');
            app.XsizeEditField_5.Value = 1024;
            app.YsizeEditField_5Label = uilabel(app.masksizePanel_4);
            app.YsizeEditField_5Label.Text = 'Y size';
            app.YsizeEditField_5 = uieditfield(app.masksizePanel_4, 'numeric');
            app.YsizeEditField_5.Limits = [0 Inf];
            app.YsizeEditField_5.Value = 1024;
            app.GratingForkedPanel_3 = uipanel(app.ParametersPanel_4);
            app.GratingForkedPanel_3.Title = 'Grating/Forked';
            app.yLabel_4 = uilabel(app.GratingForkedPanel_3);
            app.yLabel_4.Text = 'Y';
            app.groovesY_4 = uieditfield(app.GratingForkedPanel_3, 'numeric');
            app.NgroovesLabel_4 = uilabel(app.GratingForkedPanel_3);
            app.NgroovesLabel_4.Text = 'Nº grooves ';
            app.XLabel_4 = uilabel(app.GratingForkedPanel_3);
            app.XLabel_4.Text = 'X';
            app.groovesX_4 = uieditfield(app.GratingForkedPanel_3, 'numeric');
            app.groovesX_4.Value = 5;
            app.GratingmaskButton_4 = uibutton(app.GratingForkedPanel_3, 'push');
            app.GratingmaskButton_4.Text = 'Grating mask';
            app.ForkedmaskButton_4 = uibutton(app.GratingForkedPanel_3, 'push');
            app.ForkedmaskButton_4.Text = 'Forked mask';
            app.BesselparametersPanel = uipanel(app.ParametersPanel_4);
            app.BesselparametersPanel.Title = 'Bessel parameters';
            app.OrderSpinnerLabel = uilabel(app.BesselparametersPanel);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.BesselparametersPanel);
            app.PhaserangeSliderLabel = uilabel(app.BesselparametersPanel);
            app.PhaserangeSliderLabel.Text = 'Phase range';
            app.PhaserangeSlider = uislider(app.BesselparametersPanel, 'range');
            app.PhaserangeSlider.Limits = [0 6.28318530717959];
            app.PhaserangeSlider.Value = [0 6.28318530717959];
            app.PhaseLabel_5 = uilabel(app.BesselTab);
            app.PhaseLabel_5.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel_4 = uilabel(app.BesselTab);
            app.IntensitiytheoreticalresultsLabel_4.Text = 'Intensitiy - theoretical results';
            app.SendtoSLMButton_3 = uibutton(app.BesselTab, 'push');
            app.SendtoSLMButton_3.Text = 'Send to SLM';
            app.ExternalmaskTab = uitab(app.TabGroup);
            app.ExternalmaskTab.Title = 'External mask';
            app.UIAxes5 = uiaxes(app.ExternalmaskTab);
            title(app.UIAxes5, 'mask')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            colormap(app.UIAxes5, 'jet')
            app.masksListBoxLabel = uilabel(app.ExternalmaskTab);
            app.masksListBoxLabel.Text = 'masks';
            app.masksListBox = uilistbox(app.ExternalmaskTab);
            app.loadnewmaskButton = uibutton(app.ExternalmaskTab, 'push');
            app.loadnewmaskButton.Text = 'load new mask';
            app.loadtoslmButton = uibutton(app.ExternalmaskTab, 'push');
            app.loadtoslmButton.Text = 'load to slm';
            app.GSTab = uitab(app.TabGroup);
            app.GSTab.Title = 'GS';
            app.UIAxes4 = uiaxes(app.GSTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            colormap(app.UIAxes4, 'jet')
            app.GerchbergSaxtonAlgorithmPanel = uipanel(app.GSTab);
            app.GerchbergSaxtonAlgorithmPanel.Title = 'Gerchberg  Saxton Algorithm';
            app.ApertureCheckBox = uicheckbox(app.GerchbergSaxtonAlgorithmPanel);
            app.ApertureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApertureCheckBoxValueChanged, true);
            app.ApertureCheckBox.Text = 'Aperture';
            app.IterationsEditFieldLabel = uilabel(app.GerchbergSaxtonAlgorithmPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.GerchbergSaxtonAlgorithmPanel, 'numeric');
            app.IterationsEditField.Value = 100;
            app.ShowCenterButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.ShowCenterButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCenterButtonPushed, true);
            app.ShowCenterButton.Text = 'Show Center';
            app.SelectCenterButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.SelectCenterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCenterButtonPushed, true);
            app.SelectCenterButton.Text = 'Select Center';
            app.runButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.runButton.Text = 'run';
            app.loadmaskButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.loadmaskButton.Text = 'load mask';
            app.helpTab = uitab(app.TabGroup);
            app.helpTab.Title = 'help';
            app.alignmentTextAreaLabel = uilabel(app.helpTab);
            app.alignmentTextAreaLabel.Text = 'alignment';
            app.alignmentTextArea = uitextarea(app.helpTab);
            app.modulationTextAreaLabel = uilabel(app.helpTab);
            app.modulationTextAreaLabel.Text = 'modulation';
            app.modulationTextArea = uitextarea(app.helpTab);
            app.GSTextAreaLabel = uilabel(app.helpTab);
            app.GSTextAreaLabel.Text = 'GS ';
            app.GSTextArea = uitextarea(app.helpTab);
            app.ExternalmaskTextAreaLabel = uilabel(app.helpTab);
            app.ExternalmaskTextAreaLabel.Text = 'External mask';
            app.ExternalmaskTextArea = uitextarea(app.helpTab);
            app.IntensityCCDresultsLabel = uilabel(app.UIFigure);
            app.IntensityCCDresultsLabel.Text = 'Intensity - CCD results';
            app.AutoUpdateSwitch_2Label = uilabel(app.UIFigure);
            app.AutoUpdateSwitch_2Label.Text = 'Auto Update';
            app.AutoUpdateSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.snapshotButton_2 = uibutton(app.UIFigure, 'push');
            app.snapshotButton_2.Text = 'snapshot';
            app.connectedLampLabel = uilabel(app.UIFigure);
            app.connectedLampLabel.Text = 'connected';
            app.connectedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_interfacebackup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end