classdef SLM_interfacecolor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HTML                           matlab.ui.control.HTML
        SendtoSLMButton                matlab.ui.control.Button
        SimulationparametersPanel      matlab.ui.container.Panel
        dzEditField                    matlab.ui.control.NumericEditField
        dzEditFieldLabel               matlab.ui.control.Label
        w0EditField                    matlab.ui.control.NumericEditField
        w0EditFieldLabel               matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        LEditFieldLabel                matlab.ui.control.Label
        wlEditField                    matlab.ui.control.NumericEditField
        lambdaLabel                    matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        RunsimuationButton             matlab.ui.control.Button
        connectedLamp                  matlab.ui.control.Lamp
        connectedLampLabel             matlab.ui.control.Label
        snapshotButton                 matlab.ui.control.Button
        AutoUpdateSwitch               matlab.ui.control.Switch
        AutoUpdateSwitch_2Label        matlab.ui.control.Label
        IntensityCCDresultsLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AlignmentTab                   matlab.ui.container.Tab
        ParametersPanel                matlab.ui.container.Panel
        OrientationPanel               matlab.ui.container.Panel
        VerticalButton                 matlab.ui.control.Button
        HorizontalButton               matlab.ui.control.Button
        align_y                        matlab.ui.control.NumericEditField
        YsizeEditFieldLabel            matlab.ui.control.Label
        align_x                        matlab.ui.control.NumericEditField
        XsizeEditFieldLabel            matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        LampLabel                      matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        SLMsizeEditField               matlab.ui.control.NumericEditField
        SLMsizeEditFieldLabel          matlab.ui.control.Label
        cameraDropDown                 matlab.ui.control.DropDown
        cameraDropDownLabel            matlab.ui.control.Label
        PhaseLabel_align               matlab.ui.control.Label
        IntensitiytheoreticalresultsLabel_2  matlab.ui.control.Label
        intensity_align                matlab.ui.control.UIAxes
        phase_align                    matlab.ui.control.UIAxes
        LaguerreGaussTab               matlab.ui.container.Tab
        ParametersPanel_LG             matlab.ui.container.Panel
        LGButton                       matlab.ui.control.Button
        LG_forked                      matlab.ui.control.Button
        LG_grating                     matlab.ui.control.Button
        GratingForkedPanel_LG          matlab.ui.container.Panel
        XLabel_7                       matlab.ui.control.Label
        LG_grooves_x                   matlab.ui.control.NumericEditField
        NgroovesLabel_7                matlab.ui.control.Label
        yLabel_7                       matlab.ui.control.Label
        LG_groove_y                    matlab.ui.control.NumericEditField
        LaguerreGaussordersPanel       matlab.ui.container.Panel
        PhaserangeLabel                matlab.ui.control.Label
        LG_angle                       matlab.ui.control.NumericEditField
        angleEditField_5Label          matlab.ui.control.Label
        LG_aspect                      matlab.ui.control.NumericEditField
        aspectEditField_5Label         matlab.ui.control.Label
        LG_y_offset                    matlab.ui.control.NumericEditField
        XEditField_3Label_5            matlab.ui.control.Label
        LG_y_center                    matlab.ui.control.NumericEditField
        XEditField_2Label_5            matlab.ui.control.Label
        LG_radius                      matlab.ui.control.NumericEditField
        RadiusLabel                    matlab.ui.control.Label
        offsetLabel_5                  matlab.ui.control.Label
        LG_x_offset                    matlab.ui.control.NumericEditField
        centerLabel_5                  matlab.ui.control.Label
        LG_x_center                    matlab.ui.control.NumericEditField
        LG_phase_range                 matlab.ui.control.RangeSlider
        rmodeSpinner                   matlab.ui.control.Spinner
        rmodeSpinner_2Label            matlab.ui.control.Label
        amodeSpinner                   matlab.ui.control.Spinner
        amodeSpinner_2Label            matlab.ui.control.Label
        masksizePanel_2                matlab.ui.container.Panel
        LG_y_size                      matlab.ui.control.NumericEditField
        YsizeEditField_3Label          matlab.ui.control.Label
        LG_x_size                      matlab.ui.control.NumericEditField
        XsizeEditField_3Label          matlab.ui.control.Label
        IntensitiytheoreticalresultsLabel  matlab.ui.control.Label
        PhaseLabel_LG                  matlab.ui.control.Label
        phase_LG                       matlab.ui.control.UIAxes
        intensity_LG                   matlab.ui.control.UIAxes
        HermiteGaussTab                matlab.ui.container.Tab
        IntensitiytheoreticalresultsLabel_3  matlab.ui.control.Label
        PhaseLabel_4                   matlab.ui.control.Label
        ParametersPanel_3              matlab.ui.container.Panel
        HGButton                       matlab.ui.control.Button
        HG_forked                      matlab.ui.control.Button
        HG_grating                     matlab.ui.control.Button
        GratingForkedPanel_HG          matlab.ui.container.Panel
        XLabel_5                       matlab.ui.control.Label
        HG_grooves_x                   matlab.ui.control.NumericEditField
        NgroovesLabel_5                matlab.ui.control.Label
        yLabel_5                       matlab.ui.control.Label
        HG_grooves_y                   matlab.ui.control.NumericEditField
        HermiteGaussorderPanel         matlab.ui.container.Panel
        HG_angle                       matlab.ui.control.NumericEditField
        angleEditField_4Label          matlab.ui.control.Label
        HG_aspect                      matlab.ui.control.NumericEditField
        aspectEditField_4Label         matlab.ui.control.Label
        HG_y_offset_4                  matlab.ui.control.NumericEditField
        XEditField_3Label_4            matlab.ui.control.Label
        HG_y_center                    matlab.ui.control.NumericEditField
        XEditField_2Label_4            matlab.ui.control.Label
        HG_scale                       matlab.ui.control.NumericEditField
        ScaleEditField_4Label          matlab.ui.control.Label
        offsetLabel_4                  matlab.ui.control.Label
        HG_x_offset_4                  matlab.ui.control.NumericEditField
        centerLabel_4                  matlab.ui.control.Label
        HG_x_center                    matlab.ui.control.NumericEditField
        HG_range_phase                 matlab.ui.control.RangeSlider
        YmodeSpinner                   matlab.ui.control.Spinner
        YmodeSpinner_2Label            matlab.ui.control.Label
        XmodeSpinner                   matlab.ui.control.Spinner
        XmodeSpinner_2Label            matlab.ui.control.Label
        masksizePanel_3                matlab.ui.container.Panel
        HG_y_size                      matlab.ui.control.NumericEditField
        YsizeEditField_4Label          matlab.ui.control.Label
        HG_x_size                      matlab.ui.control.NumericEditField
        XsizeEditField_4Label          matlab.ui.control.Label
        phase_HG                       matlab.ui.control.UIAxes
        intensity_HG                   matlab.ui.control.UIAxes
        BesselTab                      matlab.ui.container.Tab
        IntensitiytheoreticalresultsLabel_4  matlab.ui.control.Label
        PhaseLabel_5                   matlab.ui.control.Label
        ParametersPanel_4              matlab.ui.container.Panel
        BesselButton                   matlab.ui.control.Button
        ForkedmaskButton_6             matlab.ui.control.Button
        GratingmaskButton_6            matlab.ui.control.Button
        GratingForkedPanel_Bessel      matlab.ui.container.Panel
        XLabel_6                       matlab.ui.control.Label
        groovesX_6                     matlab.ui.control.NumericEditField
        NgroovesLabel_6                matlab.ui.control.Label
        yLabel_6                       matlab.ui.control.Label
        groovesY_6                     matlab.ui.control.NumericEditField
        BesselparametersPanel          matlab.ui.container.Panel
        angleEditField                 matlab.ui.control.NumericEditField
        angleEditFieldLabel            matlab.ui.control.Label
        aspectEditField                matlab.ui.control.NumericEditField
        aspectEditFieldLabel           matlab.ui.control.Label
        bessel_y_offset                matlab.ui.control.NumericEditField
        XEditField_3Label              matlab.ui.control.Label
        offsetLabel                    matlab.ui.control.Label
        bessel_x_offset                matlab.ui.control.NumericEditField
        centerLabel                    matlab.ui.control.Label
        bessel_y_center                matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        bessel_x_center                matlab.ui.control.NumericEditField
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        PhaserangeSlider               matlab.ui.control.RangeSlider
        OrderSpinner                   matlab.ui.control.Spinner
        OrderSpinnerLabel              matlab.ui.control.Label
        masksizePanel_4                matlab.ui.container.Panel
        YsizeEditField_Bessel          matlab.ui.control.NumericEditField
        YsizeEditField_5Label          matlab.ui.control.Label
        XsizeEditField_Bessel          matlab.ui.control.NumericEditField
        XsizeEditField_5Label          matlab.ui.control.Label
        phase_bessel                   matlab.ui.control.UIAxes
        intensity_bessel               matlab.ui.control.UIAxes
        OAMTab                         matlab.ui.container.Tab
        ParametersPanel_5              matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        GratingForkedPanel_OAM         matlab.ui.container.Panel
        ForkedmaskButton_OAM           matlab.ui.control.Button
        XLabel_4                       matlab.ui.control.Label
        groovesX_OAM                   matlab.ui.control.NumericEditField
        GratingmaskButton_OAM          matlab.ui.control.Button
        NgroovesLabel_4                matlab.ui.control.Label
        yLabel_4                       matlab.ui.control.Label
        groovesY_OAM                   matlab.ui.control.NumericEditField
        ExternalmaskTab                matlab.ui.container.Tab
        loadtoslmButton                matlab.ui.control.Button
        loadnewmaskButton              matlab.ui.control.Button
        masksListBox                   matlab.ui.control.ListBox
        masksListBoxLabel              matlab.ui.control.Label
        GSTab                          matlab.ui.container.Tab
        GerchbergSaxtonAlgorithmPanel  matlab.ui.container.Panel
        loadmaskButton                 matlab.ui.control.Button
        runButton                      matlab.ui.control.Button
        SelectCenterButton             matlab.ui.control.Button
        ShowCenterButton               matlab.ui.control.Button
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        ApertureCheckBox               matlab.ui.control.CheckBox
        UIAxes4                        matlab.ui.control.UIAxes
        helpTab                        matlab.ui.container.Tab
        ExternalmaskTextArea           matlab.ui.control.TextArea
        ExternalmaskTextAreaLabel      matlab.ui.control.Label
        GSTextArea                     matlab.ui.control.TextArea
        GSTextAreaLabel                matlab.ui.control.Label
        modulationTextArea             matlab.ui.control.TextArea
        modulationTextAreaLabel        matlab.ui.control.Label
        alignmentTextArea              matlab.ui.control.TextArea
        alignmentTextAreaLabel         matlab.ui.control.Label
        SLMCam                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X;
        Y;
        x;
        y;
        width;
        height;
        phase;
        Phase;
        phase_map;
        phase_grating;
        phase_forked;
        video;
        phase_type;
        E0;
        h;
        delta;
        intensity;
        intensity_loc;

    end
    
    
    methods (Access = private)
        %% masks 

        %  spiral mask
        function [mask] = spiral_mask(app)
             
             phi = angle(app.X+1i*app.Y);               %azimuthal angle
             m = app.TopologicalChargeSpinner.Value;
             mask=mod(m*phi,2*pi);
            
             
        end
        % find center of the object
        function [center, radius] = findCenter(~, img)
            minSize = 40;
            img = bwareopen(img,minSize);
            rgs = regionprops(img);
            [~,idx] = max([rgs.Area]);
            rgs(idx) = [];
            center = vertcat(rgs.Centroid);
            [center, radius] = minboundcircle(center(:,1),center(:,2));
            
        end
        % find center of the object manually
        function [row, col] = FindCenterMan(~,vid)
           img = getsnapshot(vid);
           if length(size(img))~= 2
                img= im2double(rgb2gray(img));
           end
            
       
            figure()
            imshow(img)
            set(gcf, 'pointer', 'arrow');
            zoom on;
            pause() % you can zoom with your mouse and when your image is okay, you press any key
            zoom off; % to escape the zoom mode
            [col,row] = ginput(2);
            zoom out; % go to the original size of your image
            close()
                
         end


        % forked mask
        function [mask] = forked_mask(~,phase1,phase2)
            mask = mod(angle(exp(1i.*phase1+1i.*phase2)),2*pi);
        end


        function b = show_matrix(app, matrix, component_name)
            b = false;
            if isprop(app, component_name) && isa(app.(component_name), 'matlab.ui.control.UIAxes') && contains(component_name,"intensity")
                imagesc(app.x,app.y,matrix, 'Parent', app.(component_name));
                colormap(app.(component_name),"jet")
                b = true;
            elseif contains(component_name,"phase")
                disp('phase')
                imagesc(app.x,app.y,matrix, 'Parent', app.(component_name));
                colormap(app.(component_name),"hot")

            else
                disp(['O componente ', component_name, ' não é válido ou não é um UIAxes.']);
            end
        end

        function b = send_to_SLM(app)
            
            heds_show_phasevalues(app.phase);
        
        end

        function a = SetSimParam(app)

            lambda = app.wlEditField.Value *10^-9; % Comprimento de onda do laser em metros
            w0 = app.w0EditField.Value; % Raio da cintura do feixe Gaussiano em metros
            z = 1; % Distância de propagação em metros
            % Criação da grade espacial
            N = app.NEditField.Value; % Número de pontos na grade
            L = app.LEditField.Value; % Tamanho da grade em metros
            app.x = linspace(-L/2, L/2, N);
            app.y = linspace(-L/2, L/2, N);
            [X, Y] = meshgrid(app.x, app.y);
            r = sqrt(X.^2 + Y.^2);
            % Campo elétrico do feixe Gaussiano
            app.E0 = exp(-(r.^2)/w0^2);
            k = 2 * pi / lambda; % Número de onda
            app.delta = L / N; % Resolução espacial
            % Ddoa convolução 
            dz = app.dzEditField.Value;
            fX = (-N/2 : N/2-1) / (N*app.delta);
            [x2, y2] = meshgrid(lambda * dz * fX);
            app.h = exp(1i*k/(2*dz)*(x2.^2+y2.^2)) / (1i*lambda*dz);
            clear('fX');

        end
        

        % Função para convolução 2D
        function G = ft2(~,g, delta)
            % function G = ft2(g, delta)
            G = fftshift(fft2(fftshift(g))) * delta^2;
        end
        
        function g = ift2(~,G, delta_f)
            % function g = ift2(G, delta_f)
            N = size(G, 1);
            g = ifftshift(ifft2(ifftshift(G))) * (N * delta_f)^2;

        end

        function C = conv2D(app,A, B, delta)
            % function C = myconv2(A, B, delta)
            N = size(A, 1);

            C = ift2(app,ft2(app,A, delta) .* ft2(app,B, delta), 1/(N*delta));
        end


        function E_propagated = propagation(app)
            E_masked= app.E0.*exp(1i*app.phase);
            E_propagated = app.h .*app.conv2D((E_masked), (E_masked), app.delta);
        end

        function E_propagated = propagationH(app)
            E_masked= app.E0.*exp(1i*app.phase);
            E_propagated = app.h .*app.conv2D((E_masked), (E_masked), app.delta);
        end
        % grating mask
        function [mask] = grating_mask(app,x_grooves,y_grooves,size)
            gx = x_grooves./size(1);
            gy = y_grooves./size(2);
            mask = mod(2*pi*(app.Y.*gy+app.X.*gx),2*pi);
        end


    end
    
    methods (Access = public,Static)
        
        function mask = mask_gen_vh(N,M,V)
            
            if(V)
                vertical_mask=zeros(N,M);
                vertical_mask(:, 1:M/2) = 2*pi; 
                %mask = SLM_functions.centralized_mask(vertical_mask);
                mask = vertical_mask;
            else
                horizontal_mask = zeros(N,M);
                horizontal_mask(1:N/2,: ) = 2*pi; 
                %mask = SLM_functions.centralized_mask(horizontal_mask) ;
                mask = horizontal_mask;
            end
            
        
        end
        function [c_mask] = centralized_mask(mask)

            % Especificações do SLM : dimensões da matriz
            target_N = 1080;  
            target_M = 1920;
            [N , M] = size(mask);
            if N == target_N || M == target_M
                c_mask = mask;
            else
                % matriz para centralizar a mascara no tamanho do sensor
                center_y = floor(target_N / 2);
                center_x = floor(target_M / 2);
                start_y = center_y - floor(N / 2);
                end_y = start_y + N - 1;
                start_x = center_x - floor(M / 2);
                end_x = start_x + M - 1;
                centralized_phase = zeros(target_N,target_M);
                centralized_phase(start_y:end_y, start_x:end_x) = mask;
                c_mask = centralized_phase;
            end
            
         end
        function [center,radius] = minboundcircle(x,y,hullflag)
    % minboundcircle: Compute the minimum radius enclosing circle of a set of (x,y) pairs
    % usage: [center,radius] = minboundcircle(x,y,hullflag)
    %
    % arguments: (input)
    %  x,y - vectors of points, describing points in the plane as
    %        (x,y) pairs. x and y must be the same size. If x and y
    %        are arrays, they will be unrolled to vectors.
    %
    %  hullflag - boolean flag - allows the user to disable the
    %        call to convhulln. This will allow older releases of
    %        matlab to use this code, with a possible time penalty.
    %        It also allows minboundellipse to call this code
    %        efficiently.
    % 
    %        hullflag = false --> do not use the convex hull
    %        hullflag = true  --> use the convex hull for speed
    %
    %        default: true
    %
    %
    % arguments: (output)
    %  center - 1x2 vector, contains the (x,y) coordinates of the
    %        center of the minimum radius enclosing circle
    %
    %  radius - scalar - denotes the radius of the minimum
    %        enclosing circle
    %
    %
    % Example usage:
    %   x = randn(50000,1);
    %   y = randn(50000,1);
    %   tic,[c,r] = minboundcircle(x,y);toc
    %
    %   Elapsed time is 0.171178 seconds.
    %
    %   c: [-0.2223 0.070526]
    %   r: 4.6358
    %
    %
    % See also: minboundrect
    %
    %
    % Author: John D'Errico
    % E-mail: woodchips@rochester.rr.com
    % Release: 1.0
    % Release date: 1/10/07
    % default for hullflag
    if (nargin<3) || isempty(hullflag)
      hullflag = true;
    elseif ~islogical(hullflag) && ~ismember(hullflag,[0 1])
      error 'hullflag must be true or false if provided'
    end
    % preprocess data
    x=x(:);
    y=y(:);
    % not many error checks to worry about
    n = length(x);
    if n~=length(y)
      error 'x and y must be the same sizes'
    end
    % start out with the convex hull of the points to
    % reduce the problem dramatically. Note that any
    % points in the interior of the convex hull are
    % never needed.
    if hullflag && (n>3)
      edges = convhulln([x,y]);
      % list of the unique points on the convex hull itself
      % convhulln returns them as edges
      edges = unique(edges(:));
      % exclude those points inside the hull as not relevant
      x = x(edges);
      y = y(edges);
        
    end
    % now we must find the enclosing circle of those that
    % remain.
    n = length(x);
    % special case small numbers of points. If we trip any
    % of these cases, then we are done, so return.
    switch n
      case 0
        % empty begets empty
        center = [];
        radius = [];
        return
      case 1
        % with one point, the center has radius zero
        center = [x,y];
        radius = 0;
        return
      case 2
        % only two points. center is at the midpoint
        center = [mean(x),mean(y)];
        radius = norm([x(1),y(1)] - center);
        return
      case 3
        % exactly 3 points
        [center,radius] = enc3(x,y);
        return
    end
    % more than 3 points.
    % Use an active set strategy.
    aset = 1:3; % arbitrary, but quite adequate
    iset = 4:n;
    % pick a tolerance
    tol = 10*eps*(max(abs(mean(x) - x)) + max(abs(mean(y) - y)));
    % Keep a list of old sets as tried to trap any cycles. we don't need to
    % retain a huge list of sets, but only a few of the best ones. Any cycle
    % must hit one of these sets. Really, I could have used a smaller list,
    % but this is a small enough size that who cares? Almost always we will
    % never even fill up this list anyway.
    old.sets = NaN(10,3);
    old.rads = inf(10,1);
    old.centers = NaN(10,2);
    flag = true;
    while flag
      % have we seen this set before? If so, then we have entered a cycle
      aset = sort(aset);
      if ismember(aset,old.sets,'rows')
        % we have seen it before, so trap out
        center = old.centers(1,:);
        radius = old.radius(1);
        
        % just reset flag then continue, and the while loop will terminate
        flag = false;
        continue
      end
      
      % get the enclosing circle for the current set
      [center,radius] = enc3(x(aset),y(aset));
      
      % is this better than something from the retained sets?
      if radius < old.rads(end)
        old.sets(end,:) = sort(aset);
        old.rads(end) = radius;
        old.centers(end,:) = center;
            
        % sort them in increasing order of the circle radii
        [old.rads,tags] = sort(old.rads,'ascend');
        old.sets = old.sets(tags,:);
        old.centers = old.centers(tags,:);
      end
      
      % are all the inactive set points inside the circle?
      r = sqrt((x(iset) - center(1)).^2 + (y(iset) - center(2)).^2);
      [rmax,k] = max(r);
      if (rmax - radius) <= tol
        % the active set enclosing circle also enclosed
        % all of the inactive points.
        flag = false;
      else
        % it must be true that we can replace one member of aset
        % with iset(k). Which one?
        s1 = [aset([2 3]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(1)),y(aset(1))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(1);
          aset = [iset(k),aset([2 3])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        s1 = [aset([1 3]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(2)),y(aset(2))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(2);
          aset = [iset(k),aset([1 3])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        s1 = [aset([1 2]),iset(k)];
        [c1,r1] = enc3(x(s1),y(s1));
        if (norm(c1 - [x(aset(3)),y(aset(3))]) <= r1)
          center = c1;
          radius = r1;
          
          % update the active/inactive sets
          swap = aset(3);
          aset = [iset(k),aset([1 2])];
          iset(k) = swap;
          
          % bounce out to the while loop
          continue
        end
        
        % if we get through to this point, then something went wrong.
        % Active set problem. Increase tol, then try again.
        tol = 2*tol;
        
      end
      
    end
    end
        function [center,radius] = enc3(X,Y)
    % minimum radius enclosing circle for exactly 3 points
    %
    % x, y are 3x1 vectors
    % convert to complex
    xy = X + sqrt(-1)*Y;
    % just in case the points are collinear or nearly so, get
    % the interpoint distances, and test the farthest pair
    % to see if they work.
    Dij = @(XY,i,j) abs(XY(i) - XY(j));
    D12 = Dij(xy,1,2);
    D13 = Dij(xy,1,3);
    D23 = Dij(xy,2,3);
    % Find the most distant pair. Test if their circumcircle
    % also encloses the third point.
    if (D12>=D13) && (D12>=D23)
      center = (xy(1) + xy(2))/2;
      radius = D12/2;
      if abs(center - xy(3)) <= radius
        center = [real(center),imag(center)];
        return
      end
    elseif (D13>=D12) && (D13>=D23)
      center = (xy(1) + xy(3))/2;
      radius = D13/2;
      if abs(center - xy(2)) <= radius
        center = [real(center),imag(center)];
        return
      end
    elseif (D23>=D12) && (D23>=D13)
      center = (xy(2) + xy(3))/2;
      radius = D23/2;
      if abs(center - xy(1)) <= radius
        center = [real(center),imag(center)];
        return
      end
    end
    % if we drop down to here, then the points cannot
    % be collinear, so the resulting 2x2 linear system
    % of equations will not be singular.
    A = 2*[X(2)-X(1), Y(2)-Y(1); X(3)-X(1), Y(3)-Y(1)];
    rhs = [X(2)^2 - X(1)^2 + Y(2)^2 - Y(1)^2; ...
           X(3)^2 - X(1)^2 + Y(3)^2 - Y(1)^2];
         
    center = (A\rhs)';
    radius = norm(center - [X(1),Y(1)]);
    
    end
        function setParameters(p, sz, varargin)
                % Set default values for grid parameters 
            
                assert(numel(sz) == 2, 'sz must be a vector of 2 elements');
            
                parameters = {'offset', 'angle','centre',  'aspect'};
            
                ip = inputParser;
            
                ip.addParameter('offset', [0, 0]);
                ip.addParameter('angle', 0);
                ip.addParameter('centre', (sz)/2);
                ip.addParameter('aspect', 1.0);
                ip.addParameter('skip', {});
                ip.parse(varargin{:});
            
                for pp = 1:length(parameters)
                    if ~any(strcmpi(parameters{pp}, ip.Results.skip))
                        p.addParameter(parameters{pp}, ip.Results.(parameters{pp}));
                    end
                end
            end
        function [xx, yy] = grid2D(sz, varargin)

            % Generate a grid of points with some options:
        
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   (default [0,0])
            %   - 'angle'       angle  -- Rotation angle about axis (default: 0 radians)
            %   - 'centre'      [x, y] -- centre location for lens (default: min(sz)/2)
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
        
            % Set parameteres
            p = inputParser;
            setParameters(p, sz);
            parse(p, varargin{:});
            % Generate coordinates
            [xx, yy] = meshgrid(1:sz(2), 1:sz(1));
            % Centralize
            xx = xx - p.Results.centre(1);
            yy = yy - p.Results.centre(2);
        
            % Rotate
            ang = p.Results.angle;
            
            xx = cos(ang) .* xx - sin(ang) .* yy;
            yy = sin(ang) .* xx + cos(ang) .* yy;
            % Reshape yy
            yy = yy * p.Results.aspect;
            % Translate
            xx = xx - p.Results.offset(1);
            yy = yy - p.Results.offset(2);
        end
        function L = fastLaguerre(n, alpha, x)
            % (just to do the work faster than built in laguerre matlab
            % Calculate the generalized Laguerre polynomial of degree n and parameter alpha
            L = zeros(size(x));
            for k = 0:n
                coeff = (-1)^k * factorial(n + alpha) / (factorial(k) * factorial(n - k) * factorial(alpha + k));
                L = L + coeff * x.^k;
            end
        end
        function phase_mask = laguerre_gauss_phase_mask(sz, amode, rmode, varargin)
            % Generates the phase patterns for Laguerre-Gaussian beams
            % Parameters
            %   - sz -- size of the pattern [rows, cols]
            %   - amode -- azimuthal mode order
            %   - rmode -- radial mode orders 
            % Usage
            %   pattern = laguerre_gauss_phase_mask(sz, amode, rmode,...);
            %
            % Optional named parameters:
            %   - 'scale'       scale  -- radial scaling factor for pattern
            %   - 'centre'      [x, y] -- centre location for lens (default: sz/2)
            %   - 'offset'      [x, y] -- offset after applying transformations
            %   - 'aspect'      aspect -- aspect ratio of lens (default: 1.0)
            %   - 'angle'       angle  -- Rotation angle about axis (radians)
            %   - 'range'       [min_val, max_val] -- Range of phase values
        
            % Set parameters
            p = inputParser;
            setParameters(p, sz);  
            p.addParameter('radius', min(sz)/10);
            p.addParameter('range', [0, 2*pi]); % Default range
            p.parse(varargin{:});
        
            c = p.Results.centre;
            ofs = p.Results.offset;
            asp = p.Results.aspect;
            ang = p.Results.angle;
            range = p.Results.range;
            
            % Generate coordinates
            [xx, yy] = grid2D(sz, 'centre', c, 'offset', ofs, ...
                'aspect', asp, 'angle', ang);
        
            % Calculate the azimuthal part of the pattern
            phase_azimuthal = amode .* atan2(yy, xx);
        
            % Calculate the Laguerre polynomials in the radial direction
            rho = sqrt(xx.^2 + yy.^2);
            Lpoly_rho = fastLaguerre(rmode, abs(amode), (rho./p.Results.radius).^2);
        
            % Calculate the radial part of the phase
            phase_radial = (sign(Lpoly_rho) > 0) * pi;
        
            % Sum azimuthal and radial phases
            phase = phase_azimuthal + phase_radial;
        
            % Calculate the phase range
            phase_range = range(2) - range(1);
        
            % Normalize phase to the specified range
            phase_diff = range(1) - min(phase(:));
            phase_mask = phase + phase_diff;
            
            % Ensure phase is within the specified range
            phase_mask = mod(phase_mask - range(1), phase_range) + range(1);
        end


    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % 
            % 
            % addpath('C:\Users\LabDark\Desktop\Afonso\codigos_SLM\SLM Display SDK (MATLAB) v1.1.1 Examples\');
            % instrreset,
            % %% SLM
            % % Import SDK:
            % add_heds_path;
            % 
            % % Detect SLMs and open a window on the selected SLM:
            % heds_init_slm;
            % %% CCD
            % imaqhwinfo('winvideo')
            % %testar com instrhwinfo ou serialportlist
            % % select the device with the name: 'DO USB Video Capture';
            % % e.g., 
            % ndevice = 1;
            % a = imaqhwinfo('winvideo',ndevice);
            % % Select the format for the aquisition;
            % nformat = 8;
            % vid = videoinput('winvideo',ndevice,a.SupportedFormats{nformat});
            % app.video = vid;
            % vidRes = app.video.VideoResolution;
            % nBands = app.video.NumberOfBands;
            % app.video.FramesPerTrigger = 20;
            % hImage = image(ones(vidRes(1),vidRes(2),nBands));
            % 
            % 
            % video_preview = preview(app.video,hImage);
            % video_preview.Parent = app.SLMCam; 


            app.width = app.align_x.Value;
            app.height = app.align_y.Value;
            app.phase_map = zeros(app.height,app.width);
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);

            % Simulação
            % Parâmetros comuns
            lambda = app.wlEditField.Value *10^-9; % Comprimento de onda do laser em metros
            w0 = app.w0EditField.Value; % Raio da cintura do feixe Gaussiano em metros
            z = 1; % Distância de propagação em metros
            % Criação da grade espacial
            N = app.NEditField.Value; % Número de pontos na grade
            L = app.LEditField.Value; % Tamanho da grade em metros
            app.x = linspace(-L/2, L/2, N);
            app.y = linspace(-L/2, L/2, N);
            [X, Y] = meshgrid(app.x, app.y);
            r = sqrt(X.^2 + Y.^2);
            % Campo elétrico do feixe Gaussiano
            app.E0 = exp(-(r.^2)/w0^2);
            k = 2 * pi / lambda; % Número de onda
            app.delta = L / N; % Resolução espacial
            % Ddoa convolução 
            dz = app.dzEditField.Value;
            fX = (-N/2 : N/2-1) / (N*app.delta);
            [x2, y2] = meshgrid(lambda * dz * fX);
            app.h = exp(1i*k/(2*dz)*(x2.^2+y2.^2)) / (1i*lambda*dz);
            clear('fX');


        end

        % Callback function
        function SpiralmaskButtonPushed(app, event)
            mask = app.spiral_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            app.show_matrix(app,mask,'phase_OAM')            
        end

        % Callback function
        function widthchanged(app, event)
            app.width = app.XsizeEditField_2.Value;
            app.height = app.YsizeEditField_2.Value;
            app.phase_map = zeros(app.height,app.width);
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
        end

        % Callback function
        function HeigthChanged(app, event)
            app.width = app.XsizeEditField_2.Value;
            app.height = app.YsizeEditField_2.Value;
            app.phase_map = zeros(app.height,app.width);
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
        end

        % Callback function
        function GratingmaskButtonPushed(app, event)
            mask = app.grating_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            imshow(mask,[0,2*pi],'Parent',app.UIAxes3);
            
        end

        % Callback function
        function ForkedmaskButtonPushed(app, event)
            mask = app.forked_mask;
            app.phase = mask;
            mask = app.centralized_mask(mask);
            heds_show_phasevalues(mask);
            imshow(mask,[0,2*pi],'Parent',app.UIAxes3);
        end

        % Button pushed function: VerticalButton
        function VerticalButtonPushed(app, event)
            mask = app.mask_gen_vh(app.align_y.Value,app.align_x.Value,true);
            app.phase = mask;
            app.phase_type = "hermite";
            app.show_matrix(mask,'phase_align')
        end

        % Button pushed function: HorizontalButton
        function HorizontalButtonPushed(app, event)
            mask = app.mask_gen_vh(app.align_y.Value,app.align_x.Value,false);
            app.phase = mask;
            app.phase_type = "hermite";
            app.show_matrix(mask,'phase_align')
        end

        % Callback function
        function snapshotButtonPushed(app, event)
             img = getsnapshot(app.video);
            [filename,path] = uiputfile('snapshot.png','Save file name');
            imwrite(mat2gray(img), [path filename]);
            image(img)
            figure(app.UIFigure);
        end

        % Button pushed function: ShowCenterButton
        function ShowCenterButtonPushed(app, event)
            img  = getsnapshot(app.video);
            [center, radius] = app.findCenter(app,img);
            xc = center(1) ; yc = center(2);
            imshow(img)
            hold on
            plot(xc,yc,'b+','LineWidth',2,app.UIAxes4);
            images.roi.Circle(gca,'Center',[xc yc], 'Radius',radius,'Color','g');
            

        end

        % Value changed function: ApertureCheckBox
        function ApertureCheckBoxValueChanged(app, event)
            %% getting center and radius for de aperture 
            [center, radius] =app.findCenter(app, getsnapshot(app.video));


        end

        % Button pushed function: SelectCenterButton
        function SelectCenterButtonPushed(app, event)
            
        end

        % Callback function
        function HGButtonPushed(app, event)
            %
            mask = app.hermite_gauss_phase_mask(app);
            
            imshow(mask,[],'Parent',app.UIAxes3);
        end

        % Callback function
        function LGButtonPushed(app, event)
            
            mask = app.laguerre_gauss_phase_mask(app);
            
            imshow(mask,[],'Parent',app.UIAxes3);
        end

        % Button pushed function: RunsimuationButton
        function RunsimuationButtonPushed(app, event)
            app.SetSimParam();
            if num2str(app.phase_type) =="hermite"
                E_pro = app.propagationH();
                disp('dsdsd')
             
            else 
                E_pro = app.propagation();
            end
            app.intensity = abs(E_pro).^2;
            app.show_matrix(app.intensity,app.intensity_loc);
        end

        % Button pushed function: LGButton
        function LGButtonPushed2(app, event)
            sz = [app.LG_x_size.Value , app.LG_y_size.Value];
            amode = app.amodeSpinner.Value;
            rmode = app.rmodeSpinner.Value;
            radius = app.LG_radius.Value;
            center = [app.LG_x_center.Value, app.LG_y_center.Value];
            offset = [app.LG_x_offset.Value, app.LG_y_offset.Value];
            angle = app.LG_angle.Value;
            range = app.LG_phase_range.Value*pi;
            disp(range)
            aspect = app.LG_aspect.Value;
            mask = app.laguerre_gauss_phase_mask(sz,amode,rmode,...
            'radius',radius,'centre',center,'offset',offset,...
            'aspect',aspect,'angle' ,angle,'range',range);
            app.Phase = mask;
            app.phase = mask;
            app.phase_type = 'laguerre';
            app.intensity_loc = 'intensity_LG';
            app.show_matrix(mask,'phase_LG')
            app.width = sz(1);
            app.height = sz(2);


        end

        % Value changed function: LG_x_size
        function LG_x_sizeValueChanged(app, event)
            value = app.LG_x_size.Value;
            app.LG_x_center.Value = value/2;
        end

        % Value changed function: LG_y_size
        function LG_y_sizeValueChanged(app, event)
            value = app.LG_y_size.Value;
            app.LG_y_center.Value = value/2;
        end

        % Button pushed function: LG_grating
        function LG_gratingButtonPushed(app, event)
            
            x = -app.width/2:1:(app.width/2-1); 
            y = -app.height/2:1:(app.height/2-1);
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
            xg = app.LG_grooves_x.Value;
            yg = app.LG_groove_y.Value;
            size = [app.LG_x_size.Value , app.LG_y_size.Value];
            mask = app.grating_mask(xg,yg,size);
            imagesc(mask)
            app.phase_grating= mask;
            app.phase = mask;
            app.phase_type = 'grating';
            app.intensity_loc = 'intensity_LG';
            app.show_matrix(mask,'phase_LG')

        end

        % Button pushed function: LG_forked
        function LG_forkedButtonPushed(app, event)
            mask = app.forked_mask(app.Phase,app.phase_grating);
            app.phase = mask;
            app.show_matrix(mask,'phase_LG')
        end

        % Event received function: HTML
        function HTMLEventReceived(app, event)
            eventName = event.HTMLEventName;
            if strcmp(eventName,'ButtonClicked')
                number = event.HTMLEventData;
                disp("batatas");
            end 
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            disp(data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.SLMCam = uiaxes(app.UIFigure);
            xlabel(app.SLMCam, 'X')
            ylabel(app.SLMCam, 'Y')
            zlabel(app.SLMCam, 'Z')
            colormap(app.SLMCam, 'jet')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.phase_align = uiaxes(app.AlignmentTab);
            title(app.phase_align, 'Title')
            xlabel(app.phase_align, 'X')
            ylabel(app.phase_align, 'Y')
            zlabel(app.phase_align, 'Z')
            app.intensity_align = uiaxes(app.AlignmentTab);
            title(app.intensity_align, 'Title')
            xlabel(app.intensity_align, 'X')
            ylabel(app.intensity_align, 'Y')
            zlabel(app.intensity_align, 'Z')
            app.IntensitiytheoreticalresultsLabel_2 = uilabel(app.AlignmentTab);
            app.IntensitiytheoreticalresultsLabel_2.Text = 'Intensitiy - theoretical results';
            app.PhaseLabel_align = uilabel(app.AlignmentTab);
            app.PhaseLabel_align.Text = 'Phase';
            app.cameraDropDownLabel = uilabel(app.AlignmentTab);
            app.cameraDropDownLabel.Text = 'camera';
            app.cameraDropDown = uidropdown(app.AlignmentTab);
            app.SLMsizeEditFieldLabel = uilabel(app.AlignmentTab);
            app.SLMsizeEditFieldLabel.Text = 'SLM size';
            app.SLMsizeEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.SLMsizeEditField.Value = 1920;
            app.XEditFieldLabel = uilabel(app.AlignmentTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.XEditField.Value = 1080;
            app.LampLabel = uilabel(app.AlignmentTab);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.AlignmentTab);
            app.ParametersPanel = uipanel(app.AlignmentTab);
            app.ParametersPanel.Title = 'Parameters';
            app.XsizeEditFieldLabel = uilabel(app.ParametersPanel);
            app.XsizeEditFieldLabel.Text = 'X size';
            app.align_x = uieditfield(app.ParametersPanel, 'numeric');
            app.align_x.Value = 1024;
            app.YsizeEditFieldLabel = uilabel(app.ParametersPanel);
            app.YsizeEditFieldLabel.Text = 'Y size';
            app.align_y = uieditfield(app.ParametersPanel, 'numeric');
            app.align_y.Value = 1024;
            app.OrientationPanel = uipanel(app.ParametersPanel);
            app.OrientationPanel.Title = 'Orientation';
            app.HorizontalButton = uibutton(app.OrientationPanel, 'push');
            app.HorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalButtonPushed, true);
            app.HorizontalButton.Text = 'Horizontal';
            app.VerticalButton = uibutton(app.OrientationPanel, 'push');
            app.VerticalButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalButtonPushed, true);
            app.VerticalButton.Text = 'Vertical';
            app.LaguerreGaussTab = uitab(app.TabGroup);
            app.LaguerreGaussTab.Title = 'Laguerre-Gauss';
            app.intensity_LG = uiaxes(app.LaguerreGaussTab);
            xlabel(app.intensity_LG, 'X')
            ylabel(app.intensity_LG, 'Y')
            zlabel(app.intensity_LG, 'Z')
            app.phase_LG = uiaxes(app.LaguerreGaussTab);
            xlabel(app.phase_LG, 'X')
            ylabel(app.phase_LG, 'Y')
            zlabel(app.phase_LG, 'Z')
            app.PhaseLabel_LG = uilabel(app.LaguerreGaussTab);
            app.PhaseLabel_LG.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel = uilabel(app.LaguerreGaussTab);
            app.IntensitiytheoreticalresultsLabel.Text = 'Intensitiy - theoretical results';
            app.ParametersPanel_LG = uipanel(app.LaguerreGaussTab);
            app.ParametersPanel_LG.Title = 'Parameters';
            app.masksizePanel_2 = uipanel(app.ParametersPanel_LG);
            app.masksizePanel_2.Title = 'mask size';
            app.XsizeEditField_3Label = uilabel(app.masksizePanel_2);
            app.XsizeEditField_3Label.Text = 'X size';
            app.LG_x_size = uieditfield(app.masksizePanel_2, 'numeric');
            app.LG_x_size.ValueChangedFcn = createCallbackFcn(app, @LG_x_sizeValueChanged, true);
            app.LG_x_size.Value = 1024;
            app.YsizeEditField_3Label = uilabel(app.masksizePanel_2);
            app.YsizeEditField_3Label.Text = 'Y size';
            app.LG_y_size = uieditfield(app.masksizePanel_2, 'numeric');
            app.LG_y_size.Limits = [0 Inf];
            app.LG_y_size.ValueChangedFcn = createCallbackFcn(app, @LG_y_sizeValueChanged, true);
            app.LG_y_size.Value = 1024;
            app.LaguerreGaussordersPanel = uipanel(app.ParametersPanel_LG);
            app.LaguerreGaussordersPanel.Title = 'Laguerre-Gauss orders';
            app.amodeSpinner_2Label = uilabel(app.LaguerreGaussordersPanel);
            app.amodeSpinner_2Label.Text = 'a mode';
            app.amodeSpinner = uispinner(app.LaguerreGaussordersPanel);
            app.amodeSpinner.Value = 1;
            app.rmodeSpinner_2Label = uilabel(app.LaguerreGaussordersPanel);
            app.rmodeSpinner_2Label.Text = 'r mode';
            app.rmodeSpinner = uispinner(app.LaguerreGaussordersPanel);
            app.LG_phase_range = uislider(app.LaguerreGaussordersPanel, 'range');
            app.LG_phase_range.Limits = [-3 3];
            app.LG_phase_range.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.LG_phase_range.Value = [0 2];
            app.LG_x_center = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_x_center.Value = 512;
            app.centerLabel_5 = uilabel(app.LaguerreGaussordersPanel);
            app.centerLabel_5.Text = 'center';
            app.LG_x_offset = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.offsetLabel_5 = uilabel(app.LaguerreGaussordersPanel);
            app.offsetLabel_5.Text = 'offset';
            app.RadiusLabel = uilabel(app.LaguerreGaussordersPanel);
            app.RadiusLabel.Text = 'Radius';
            app.LG_radius = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_radius.Value = 25;
            app.XEditField_2Label_5 = uilabel(app.LaguerreGaussordersPanel);
            app.XEditField_2Label_5.Text = 'X';
            app.LG_y_center = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_y_center.Value = 512;
            app.XEditField_3Label_5 = uilabel(app.LaguerreGaussordersPanel);
            app.XEditField_3Label_5.Text = 'X';
            app.LG_y_offset = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.aspectEditField_5Label = uilabel(app.LaguerreGaussordersPanel);
            app.aspectEditField_5Label.Text = 'aspect';
            app.LG_aspect = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_aspect.Value = 1;
            app.angleEditField_5Label = uilabel(app.LaguerreGaussordersPanel);
            app.angleEditField_5Label.Text = 'angle';
            app.LG_angle = uieditfield(app.LaguerreGaussordersPanel, 'numeric');
            app.LG_angle.AllowEmpty = 'on';
            app.LG_angle.Placeholder = 'desde';
            app.PhaserangeLabel = uilabel(app.LaguerreGaussordersPanel);
            app.PhaserangeLabel.Text = 'Phase range';
            app.GratingForkedPanel_LG = uipanel(app.ParametersPanel_LG);
            app.GratingForkedPanel_LG.Title = 'Grating/Forked';
            app.LG_groove_y = uieditfield(app.GratingForkedPanel_LG, 'numeric');
            app.yLabel_7 = uilabel(app.GratingForkedPanel_LG);
            app.yLabel_7.Text = 'Y';
            app.NgroovesLabel_7 = uilabel(app.GratingForkedPanel_LG);
            app.NgroovesLabel_7.Text = 'Nº grooves ';
            app.LG_grooves_x = uieditfield(app.GratingForkedPanel_LG, 'numeric');
            app.LG_grooves_x.Value = 5;
            app.XLabel_7 = uilabel(app.GratingForkedPanel_LG);
            app.XLabel_7.Text = 'X';
            app.LG_grating = uibutton(app.ParametersPanel_LG, 'push');
            app.LG_grating.ButtonPushedFcn = createCallbackFcn(app, @LG_gratingButtonPushed, true);
            app.LG_grating.Text = 'Grating mask';
            app.LG_forked = uibutton(app.ParametersPanel_LG, 'push');
            app.LG_forked.ButtonPushedFcn = createCallbackFcn(app, @LG_forkedButtonPushed, true);
            app.LG_forked.Text = 'Forked mask';
            app.LGButton = uibutton(app.ParametersPanel_LG, 'push');
            app.LGButton.ButtonPushedFcn = createCallbackFcn(app, @LGButtonPushed2, true);
            app.LGButton.Text = 'L-G';
            app.HermiteGaussTab = uitab(app.TabGroup);
            app.HermiteGaussTab.Title = 'Hermite-Gauss';
            app.intensity_HG = uiaxes(app.HermiteGaussTab);
            xlabel(app.intensity_HG, 'X')
            ylabel(app.intensity_HG, 'Y')
            zlabel(app.intensity_HG, 'Z')
            app.phase_HG = uiaxes(app.HermiteGaussTab);
            xlabel(app.phase_HG, 'X')
            ylabel(app.phase_HG, 'Y')
            zlabel(app.phase_HG, 'Z')
            app.ParametersPanel_3 = uipanel(app.HermiteGaussTab);
            app.ParametersPanel_3.Title = 'Parameters';
            app.masksizePanel_3 = uipanel(app.ParametersPanel_3);
            app.masksizePanel_3.Title = 'mask size';
            app.XsizeEditField_4Label = uilabel(app.masksizePanel_3);
            app.XsizeEditField_4Label.Text = 'X size';
            app.HG_x_size = uieditfield(app.masksizePanel_3, 'numeric');
            app.HG_x_size.Value = 1024;
            app.YsizeEditField_4Label = uilabel(app.masksizePanel_3);
            app.YsizeEditField_4Label.Text = 'Y size';
            app.HG_y_size = uieditfield(app.masksizePanel_3, 'numeric');
            app.HG_y_size.Limits = [0 Inf];
            app.HG_y_size.Value = 1024;
            app.HermiteGaussorderPanel = uipanel(app.ParametersPanel_3);
            app.HermiteGaussorderPanel.Title = 'Hermite-Gauss order';
            app.XmodeSpinner_2Label = uilabel(app.HermiteGaussorderPanel);
            app.XmodeSpinner_2Label.Text = 'X mode';
            app.XmodeSpinner = uispinner(app.HermiteGaussorderPanel);
            app.YmodeSpinner_2Label = uilabel(app.HermiteGaussorderPanel);
            app.YmodeSpinner_2Label.Text = 'Y mode';
            app.YmodeSpinner = uispinner(app.HermiteGaussorderPanel);
            app.HG_range_phase = uislider(app.HermiteGaussorderPanel, 'range');
            app.HG_range_phase.Limits = [-2 2];
            app.HG_range_phase.Value = [0 1.976];
            app.HG_x_center = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.centerLabel_4 = uilabel(app.HermiteGaussorderPanel);
            app.centerLabel_4.Text = 'center';
            app.HG_x_offset_4 = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.offsetLabel_4 = uilabel(app.HermiteGaussorderPanel);
            app.offsetLabel_4.Text = 'offset';
            app.ScaleEditField_4Label = uilabel(app.HermiteGaussorderPanel);
            app.ScaleEditField_4Label.Text = 'Scale';
            app.HG_scale = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.XEditField_2Label_4 = uilabel(app.HermiteGaussorderPanel);
            app.XEditField_2Label_4.Text = 'X';
            app.HG_y_center = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.XEditField_3Label_4 = uilabel(app.HermiteGaussorderPanel);
            app.XEditField_3Label_4.Text = 'X';
            app.HG_y_offset_4 = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.aspectEditField_4Label = uilabel(app.HermiteGaussorderPanel);
            app.aspectEditField_4Label.Text = 'aspect';
            app.HG_aspect = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.angleEditField_4Label = uilabel(app.HermiteGaussorderPanel);
            app.angleEditField_4Label.Text = 'angle';
            app.HG_angle = uieditfield(app.HermiteGaussorderPanel, 'numeric');
            app.GratingForkedPanel_HG = uipanel(app.ParametersPanel_3);
            app.GratingForkedPanel_HG.Title = 'Grating/Forked';
            app.HG_grooves_y = uieditfield(app.GratingForkedPanel_HG, 'numeric');
            app.yLabel_5 = uilabel(app.GratingForkedPanel_HG);
            app.yLabel_5.Text = 'Y';
            app.NgroovesLabel_5 = uilabel(app.GratingForkedPanel_HG);
            app.NgroovesLabel_5.Text = 'Nº grooves ';
            app.HG_grooves_x = uieditfield(app.GratingForkedPanel_HG, 'numeric');
            app.HG_grooves_x.Value = 5;
            app.XLabel_5 = uilabel(app.GratingForkedPanel_HG);
            app.XLabel_5.Text = 'X';
            app.HG_grating = uibutton(app.ParametersPanel_3, 'push');
            app.HG_grating.Text = 'Grating mask';
            app.HG_forked = uibutton(app.ParametersPanel_3, 'push');
            app.HG_forked.Text = 'Forked mask';
            app.HGButton = uibutton(app.ParametersPanel_3, 'push');
            app.HGButton.Text = 'H-G';
            app.PhaseLabel_4 = uilabel(app.HermiteGaussTab);
            app.PhaseLabel_4.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel_3 = uilabel(app.HermiteGaussTab);
            app.IntensitiytheoreticalresultsLabel_3.Text = 'Intensitiy - theoretical results';
            app.BesselTab = uitab(app.TabGroup);
            app.BesselTab.Title = 'Bessel';
            app.intensity_bessel = uiaxes(app.BesselTab);
            xlabel(app.intensity_bessel, 'X')
            ylabel(app.intensity_bessel, 'Y')
            zlabel(app.intensity_bessel, 'Z')
            app.phase_bessel = uiaxes(app.BesselTab);
            xlabel(app.phase_bessel, 'X')
            ylabel(app.phase_bessel, 'Y')
            zlabel(app.phase_bessel, 'Z')
            app.ParametersPanel_4 = uipanel(app.BesselTab);
            app.ParametersPanel_4.Title = 'Parameters';
            app.masksizePanel_4 = uipanel(app.ParametersPanel_4);
            app.masksizePanel_4.Title = 'mask size';
            app.XsizeEditField_5Label = uilabel(app.masksizePanel_4);
            app.XsizeEditField_5Label.Text = 'X size';
            app.XsizeEditField_Bessel = uieditfield(app.masksizePanel_4, 'numeric');
            app.XsizeEditField_Bessel.Value = 1024;
            app.YsizeEditField_5Label = uilabel(app.masksizePanel_4);
            app.YsizeEditField_5Label.Text = 'Y size';
            app.YsizeEditField_Bessel = uieditfield(app.masksizePanel_4, 'numeric');
            app.YsizeEditField_Bessel.Limits = [0 Inf];
            app.YsizeEditField_Bessel.Value = 1024;
            app.BesselparametersPanel = uipanel(app.ParametersPanel_4);
            app.BesselparametersPanel.Title = 'Bessel parameters';
            app.OrderSpinnerLabel = uilabel(app.BesselparametersPanel);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.BesselparametersPanel);
            app.PhaserangeSlider = uislider(app.BesselparametersPanel, 'range');
            app.PhaserangeSlider.Limits = [-2 2];
            app.PhaserangeSlider.Value = [0 2];
            app.ScaleEditFieldLabel = uilabel(app.BesselparametersPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.BesselparametersPanel, 'numeric');
            app.bessel_x_center = uieditfield(app.BesselparametersPanel, 'numeric');
            app.XEditField_2Label = uilabel(app.BesselparametersPanel);
            app.XEditField_2Label.Text = 'X';
            app.bessel_y_center = uieditfield(app.BesselparametersPanel, 'numeric');
            app.centerLabel = uilabel(app.BesselparametersPanel);
            app.centerLabel.Text = 'center';
            app.bessel_x_offset = uieditfield(app.BesselparametersPanel, 'numeric');
            app.offsetLabel = uilabel(app.BesselparametersPanel);
            app.offsetLabel.Text = 'offset';
            app.XEditField_3Label = uilabel(app.BesselparametersPanel);
            app.XEditField_3Label.Text = 'X';
            app.bessel_y_offset = uieditfield(app.BesselparametersPanel, 'numeric');
            app.aspectEditFieldLabel = uilabel(app.BesselparametersPanel);
            app.aspectEditFieldLabel.Text = 'aspect';
            app.aspectEditField = uieditfield(app.BesselparametersPanel, 'numeric');
            app.angleEditFieldLabel = uilabel(app.BesselparametersPanel);
            app.angleEditFieldLabel.Text = 'angle';
            app.angleEditField = uieditfield(app.BesselparametersPanel, 'numeric');
            app.GratingForkedPanel_Bessel = uipanel(app.ParametersPanel_4);
            app.GratingForkedPanel_Bessel.Title = 'Grating/Forked';
            app.groovesY_6 = uieditfield(app.GratingForkedPanel_Bessel, 'numeric');
            app.yLabel_6 = uilabel(app.GratingForkedPanel_Bessel);
            app.yLabel_6.Text = 'Y';
            app.NgroovesLabel_6 = uilabel(app.GratingForkedPanel_Bessel);
            app.NgroovesLabel_6.Text = 'Nº grooves ';
            app.groovesX_6 = uieditfield(app.GratingForkedPanel_Bessel, 'numeric');
            app.groovesX_6.Value = 5;
            app.XLabel_6 = uilabel(app.GratingForkedPanel_Bessel);
            app.XLabel_6.Text = 'X';
            app.GratingmaskButton_6 = uibutton(app.ParametersPanel_4, 'push');
            app.GratingmaskButton_6.Text = 'Grating mask';
            app.ForkedmaskButton_6 = uibutton(app.ParametersPanel_4, 'push');
            app.ForkedmaskButton_6.Text = 'Forked mask';
            app.BesselButton = uibutton(app.ParametersPanel_4, 'push');
            app.BesselButton.Text = 'Bessel';
            app.PhaseLabel_5 = uilabel(app.BesselTab);
            app.PhaseLabel_5.Text = 'Phase';
            app.IntensitiytheoreticalresultsLabel_4 = uilabel(app.BesselTab);
            app.IntensitiytheoreticalresultsLabel_4.Text = 'Intensitiy - theoretical results';
            app.OAMTab = uitab(app.TabGroup);
            app.OAMTab.Title = 'OAM';
            app.ParametersPanel_5 = uipanel(app.OAMTab);
            app.ParametersPanel_5.Title = 'Parameters';
            app.GratingForkedPanel_OAM = uipanel(app.ParametersPanel_5);
            app.GratingForkedPanel_OAM.Title = 'Grating/Forked';
            app.groovesY_OAM = uieditfield(app.GratingForkedPanel_OAM, 'numeric');
            app.yLabel_4 = uilabel(app.GratingForkedPanel_OAM);
            app.yLabel_4.Text = 'Y';
            app.NgroovesLabel_4 = uilabel(app.GratingForkedPanel_OAM);
            app.NgroovesLabel_4.Text = 'Nº grooves ';
            app.GratingmaskButton_OAM = uibutton(app.GratingForkedPanel_OAM, 'push');
            app.GratingmaskButton_OAM.Text = 'Grating mask';
            app.groovesX_OAM = uieditfield(app.GratingForkedPanel_OAM, 'numeric');
            app.groovesX_OAM.Value = 5;
            app.XLabel_4 = uilabel(app.GratingForkedPanel_OAM);
            app.XLabel_4.Text = 'X';
            app.ForkedmaskButton_OAM = uibutton(app.GratingForkedPanel_OAM, 'push');
            app.ForkedmaskButton_OAM.Text = 'Forked mask';
            app.Panel = uipanel(app.ParametersPanel_5);
            app.Panel.Title = 'Panel';
            app.ExternalmaskTab = uitab(app.TabGroup);
            app.ExternalmaskTab.Title = 'External mask';
            app.masksListBoxLabel = uilabel(app.ExternalmaskTab);
            app.masksListBoxLabel.Text = 'masks';
            app.masksListBox = uilistbox(app.ExternalmaskTab);
            app.loadnewmaskButton = uibutton(app.ExternalmaskTab, 'push');
            app.loadnewmaskButton.Text = 'load new mask';
            app.loadtoslmButton = uibutton(app.ExternalmaskTab, 'push');
            app.loadtoslmButton.Text = 'load to slm';
            app.GSTab = uitab(app.TabGroup);
            app.GSTab.Title = 'GS';
            app.UIAxes4 = uiaxes(app.GSTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            colormap(app.UIAxes4, 'jet')
            app.GerchbergSaxtonAlgorithmPanel = uipanel(app.GSTab);
            app.GerchbergSaxtonAlgorithmPanel.Title = 'Gerchberg  Saxton Algorithm';
            app.ApertureCheckBox = uicheckbox(app.GerchbergSaxtonAlgorithmPanel);
            app.ApertureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApertureCheckBoxValueChanged, true);
            app.ApertureCheckBox.Text = 'Aperture';
            app.IterationsEditFieldLabel = uilabel(app.GerchbergSaxtonAlgorithmPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.GerchbergSaxtonAlgorithmPanel, 'numeric');
            app.IterationsEditField.Value = 100;
            app.ShowCenterButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.ShowCenterButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCenterButtonPushed, true);
            app.ShowCenterButton.Text = 'Show Center';
            app.SelectCenterButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.SelectCenterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCenterButtonPushed, true);
            app.SelectCenterButton.Text = 'Select Center';
            app.runButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.runButton.Text = 'run';
            app.loadmaskButton = uibutton(app.GerchbergSaxtonAlgorithmPanel, 'push');
            app.loadmaskButton.Text = 'load mask';
            app.helpTab = uitab(app.TabGroup);
            app.helpTab.Title = 'help';
            app.alignmentTextAreaLabel = uilabel(app.helpTab);
            app.alignmentTextAreaLabel.Text = 'alignment';
            app.alignmentTextArea = uitextarea(app.helpTab);
            app.modulationTextAreaLabel = uilabel(app.helpTab);
            app.modulationTextAreaLabel.Text = 'modulation';
            app.modulationTextArea = uitextarea(app.helpTab);
            app.GSTextAreaLabel = uilabel(app.helpTab);
            app.GSTextAreaLabel.Text = 'GS ';
            app.GSTextArea = uitextarea(app.helpTab);
            app.ExternalmaskTextAreaLabel = uilabel(app.helpTab);
            app.ExternalmaskTextAreaLabel.Text = 'External mask';
            app.ExternalmaskTextArea = uitextarea(app.helpTab);
            app.IntensityCCDresultsLabel = uilabel(app.UIFigure);
            app.IntensityCCDresultsLabel.Text = 'Intensity - CCD results';
            app.AutoUpdateSwitch_2Label = uilabel(app.UIFigure);
            app.AutoUpdateSwitch_2Label.Text = 'Auto Update';
            app.AutoUpdateSwitch = uiswitch(app.UIFigure, 'slider');
            app.snapshotButton = uibutton(app.UIFigure, 'push');
            app.snapshotButton.Text = 'snapshot';
            app.connectedLampLabel = uilabel(app.UIFigure);
            app.connectedLampLabel.Text = 'connected';
            app.connectedLamp = uilamp(app.UIFigure);
            app.RunsimuationButton = uibutton(app.UIFigure, 'push');
            app.RunsimuationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimuationButtonPushed, true);
            app.RunsimuationButton.Text = 'Run simuation';
            app.SimulationparametersPanel = uipanel(app.UIFigure);
            app.SimulationparametersPanel.Title = 'Simulation parameters';
            app.NEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.NEditField.Value = 1024;
            app.lambdaLabel = uilabel(app.SimulationparametersPanel);
            app.lambdaLabel.Text = 'wl';
            app.wlEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.wlEditField.Value = 1.55e-06;
            app.LEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.LEditField.Value = 0.2;
            app.w0EditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.w0EditFieldLabel.Text = 'w0';
            app.w0EditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.w0EditField.Value = 0.01;
            app.dzEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.dzEditFieldLabel.Text = 'dz';
            app.dzEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.dzEditField.Value = 0.1;
            app.SendtoSLMButton = uibutton(app.UIFigure, 'push');
            app.SendtoSLMButton.Text = 'Send to SLM';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'component.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @HTMLEventReceived, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_interfacecolor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end